classdef project_final < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        TabGroup                  matlab.ui.container.TabGroup
        PreProcessandProcessTab   matlab.ui.container.Tab
        ProcessPanel              matlab.ui.container.Panel
        MaterialSelectionLabel    matlab.ui.control.Label
        MaterialDropDown          matlab.ui.control.DropDown
        RunLamp                   matlab.ui.control.Lamp
        RunButton                 matlab.ui.control.Button
        MaterialPanel             matlab.ui.container.Panel
        Rhokgm3EditField          matlab.ui.control.NumericEditField
        Rhokgm3EditFieldLabel     matlab.ui.control.Label
        Acm2EditField             matlab.ui.control.NumericEditField
        Acm2Label                 matlab.ui.control.Label
        EGPaEditField             matlab.ui.control.NumericEditField
        EGPaLabel                 matlab.ui.control.Label
        BCPanel                   matlab.ui.container.Panel
        ClearBCButton             matlab.ui.control.Button
        BCTable                   matlab.ui.control.Table
        ApplyBCButton             matlab.ui.control.Button
        BCValueEditField          matlab.ui.control.NumericEditField
        ValueEditFieldLabel       matlab.ui.control.Label
        BCTypeDropDown            matlab.ui.control.DropDown
        BCTypeDropDownLabel       matlab.ui.control.Label
        DirectionDropDown         matlab.ui.control.DropDown
        DirectionLabel            matlab.ui.control.Label
        BCNodeEditField           matlab.ui.control.NumericEditField
        NodeEditFieldLabel        matlab.ui.control.Label
        PreProcessPanel           matlab.ui.container.Panel
        DeleteNodeEditField       matlab.ui.control.NumericEditField
        DeleteNodeButton          matlab.ui.control.Button
        DisplayBCSwitch           matlab.ui.control.RockerSwitch
        DisplayBCSwitchLabel      matlab.ui.control.Label
        DeleteElementButton       matlab.ui.control.Button
        DeleteElementEditField    matlab.ui.control.NumericEditField
        ElementLabelsSwitch       matlab.ui.control.Switch
        ElementLabelsSwitchLabel  matlab.ui.control.Label
        NodeLabelsSwitch          matlab.ui.control.Switch
        NodeLabelsSwitchLabel     matlab.ui.control.Label
        RemoveAllButton           matlab.ui.control.Button
        EditField2                matlab.ui.control.NumericEditField
        EditField                 matlab.ui.control.NumericEditField
        ElementsLabel_2           matlab.ui.control.Label
        NodesLabel_2              matlab.ui.control.Label
        ReadDataButton            matlab.ui.control.Button
        GenerateElementButton     matlab.ui.control.Button
        EndNode2EditField         matlab.ui.control.NumericEditField
        EndNode2EditFieldLabel    matlab.ui.control.Label
        EndNode1EditField         matlab.ui.control.NumericEditField
        EndNode1EditFieldLabel    matlab.ui.control.Label
        ElementsLabel             matlab.ui.control.Label
        GenerateNodeButton        matlab.ui.control.Button
        YEditField                matlab.ui.control.NumericEditField
        YEditFieldLabel           matlab.ui.control.Label
        XEditField                matlab.ui.control.NumericEditField
        XEditFieldLabel           matlab.ui.control.Label
        NodesLabel                matlab.ui.control.Label
        UIAxes                    matlab.ui.control.UIAxes
        Results                   matlab.ui.container.Tab
        PostProcessPanel          matlab.ui.container.Panel
        AnimateButton             matlab.ui.control.Button
        PlayModeSoundButton       matlab.ui.control.Button
        ModeSpinner               matlab.ui.control.Spinner
        ModeSpinnerLabel          matlab.ui.control.Label
        RunModalAnalysisButton_2  matlab.ui.control.Button
        ResultsPanel              matlab.ui.container.Panel
        ObjectiveValueLabel       matlab.ui.control.Label
        ObjectiveLabel            matlab.ui.control.Label
        WeightValueLabel          matlab.ui.control.Label
        WeightLabel               matlab.ui.control.Label
        ModalTable                matlab.ui.control.Table
        ResetDispButton           matlab.ui.control.Button
        ResetStressButton         matlab.ui.control.Button
        PostControlPanel          matlab.ui.container.Panel
        ColorMapDropDown          matlab.ui.control.DropDown
        ColormapLabel             matlab.ui.control.Label
        PPNodeLabelsSwitch        matlab.ui.control.Switch
        PPNodeLabelsLabel         matlab.ui.control.Label
        ShowUndeformedSwitch      matlab.ui.control.Switch
        ShowUndeformedLabel       matlab.ui.control.Label
        ScaleSlider               matlab.ui.control.Slider
        ScaleLabel                matlab.ui.control.Label
        ResultTypeDropDown        matlab.ui.control.DropDown
        ResultTypeLabel           matlab.ui.control.Label
        ModalAxes                 matlab.ui.control.UIAxes
        StressAxes                matlab.ui.control.UIAxes
        DisplacementAxes          matlab.ui.control.UIAxes
        ExtendedNodeListTab       matlab.ui.container.Tab
        extended                  matlab.ui.control.Label
        ExtendedNodeTable         matlab.ui.control.Table
        Optimization              matlab.ui.container.Tab
        NmLabel                   matlab.ui.control.Label
        PaLabel                   matlab.ui.control.Label
        mLabel                    matlab.ui.control.Label
        NLabel                    matlab.ui.control.Label
        DisplayDropDown           matlab.ui.control.DropDown
        OptObjectiveLabel         matlab.ui.control.Label
        OptStressLabel            matlab.ui.control.Label
        OptDispLabel              matlab.ui.control.Label
        OptWeightLabel            matlab.ui.control.Label
        LiveResultsLabel          matlab.ui.control.Label
        BestScoreLabel            matlab.ui.control.Label
        IterationLabel            matlab.ui.control.Label
        OptLamp                   matlab.ui.control.Lamp
        StopButton                matlab.ui.control.Button
        OptimizeButton            matlab.ui.control.Button
        OptimizationControlLabel  matlab.ui.control.Label
        OptStressAxes             matlab.ui.control.UIAxes
        OptAxes                   matlab.ui.control.UIAxes
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    
    properties (Access = private)
        Nodes = [];
        Elements = [];
        ShowNodeLabels = false;
        ShowElementLabels = false;
        BCData = cell(0,4);   % {Node, Type, Dir, Value}
        ForceBCs = [];        % [node, dir, value]
        DispBCs = [];         % [node, dir, value]
        E = 0;
        A = 0;
        MaterialDB = struct();
        % Solver / post-process results
U = [];
Reactions = [];
ElementStress = [];
ElementStrain = [];
MaxDisp = 0;
MaxStress = 0;
WeightValue = 0;
ObjectiveValue = 0;
StopRequested = false;
IsOptimizing = false;

% Material constant for bridge task
Rho = 8000;
    LastBestScore = inf;
    NoImproveCount = 0;
    StopTolerance = 0.002;   % %0.2 iyileşme eşiği
    StallGenerations = 8;    % üst üste 8 nesil iyileşmezse dur
    MinGenerations = 5;      % çok erken durmasın
    end
    
    properties (Access = public)
        %AŞAĞIDAKİNİ BEN YAZDIM BU HAREKETLİ KISIM CANLI OLSUN DİYE
        V
        freeDofs 
    end
    
 
methods (Access = private)
    function solveTruss(app)

    % Number of nodes
    nNodes = size(app.Nodes, 1);

    % Total degrees of freedom (2 per node)
    ndof = 2 * nNodes;

    % Initialize global stiffness matrix
    K = zeros(ndof, ndof);

    % Initialize force vector
    F = zeros(ndof, 1);

    % Initialize displacement vector
    U = zeros(ndof, 1);

    % Assemble global stiffness matrix
    for e = 1:size(app.Elements, 1)

        % Element end nodes
        n1 = app.Elements(e, 1);
        n2 = app.Elements(e, 2);

        % Node coordinates
        x1 = app.Nodes(n1, 1);
        y1 = app.Nodes(n1, 2);
        x2 = app.Nodes(n2, 1);
        y2 = app.Nodes(n2, 2);

        % Element length
        L = sqrt((x2 - x1)^2 + (y2 - y1)^2);

        % Direction cosines
        c = (x2 - x1) / L;
        s = (y2 - y1) / L;

        % Element stiffness matrix in global coordinates
        ke = (app.E * app.A / L) * ...
            [ c*c   c*s  -c*c  -c*s;
              c*s   s*s  -c*s  -s*s;
             -c*c  -c*s   c*c   c*s;
             -c*s  -s*s   c*s   s*s ];

        % Global DOF indices for this element
        dof = [2*n1-1, 2*n1, 2*n2-1, 2*n2];

        % Assemble into global stiffness matrix
        for i = 1:4
            for j = 1:4
                K(dof(i), dof(j)) = K(dof(i), dof(j)) + ke(i, j);
            end
        end
    end

    % Fill global force vector from applied force boundary conditions
    for i = 1:size(app.ForceBCs, 1)
        node = app.ForceBCs(i, 1);
        dir  = app.ForceBCs(i, 2);   % 1 = X, 2 = Y
        val  = app.ForceBCs(i, 3);

        dof = 2*node - (2 - dir);
        F(dof) = F(dof) + val;
    end

    % Apply displacement boundary conditions
    fixedDofs = [];
    fixedVals = [];

    for i = 1:size(app.DispBCs, 1)
        node = app.DispBCs(i, 1);
        dir  = app.DispBCs(i, 2);   % 1 = X, 2 = Y
        val  = app.DispBCs(i, 3);

        dof = 2*node - (2 - dir);

        fixedDofs(end+1) = dof;
        fixedVals(end+1) = val;

        U(dof) = val;
    end

    % Find free degrees of freedom
    allDofs = 1:ndof;
    freeDofs = setdiff(allDofs, fixedDofs);

    % Solve reduced system
    Kff = K(freeDofs, freeDofs);
    Ff  = F(freeDofs);

    % DÜZELTME 1: Sıfır olmayan displacement BC etkisini F'e yansıt
    if ~isempty(fixedDofs)
        Ff = Ff - K(freeDofs, fixedDofs) * fixedVals(:);
    end

    % DÜZELTME 2: Singular (tekil) matris kontrolü
    if isempty(freeDofs)
        uialert(app.UIFigure, ...
            'All degrees of freedom have been fixed!', ...
            'error');
        app.RunLamp.Color = [1 0 0];
        return;
    end

    if rcond(Kff) < 1e-12
        uialert(app.UIFigure, ...
            'The structure is not sufficiently constrained! Please add more displacement boundary conditions.', ...
            'Singular Matrix Error');
        app.RunLamp.Color = [1 0 0];
        return;
    end

    % Çöz
    U(freeDofs) = Kff \ Ff;
    app.Reactions = K * U - F;

    % Store results
    app.U = U;

end

function computeElementResults(app)

    % Number of elements
    nElem = size(app.Elements, 1);

    % Initialize result arrays
    app.ElementStrain = zeros(nElem, 1);
    app.ElementStress = zeros(nElem, 1);

    % Loop over all elements
    for e = 1:nElem

        % Element end nodes
        n1 = app.Elements(e, 1);
        n2 = app.Elements(e, 2);

        % Node coordinates
        x1 = app.Nodes(n1, 1);
        y1 = app.Nodes(n1, 2);
        x2 = app.Nodes(n2, 1);
        y2 = app.Nodes(n2, 2);

        % Element length
        L = sqrt((x2 - x1)^2 + (y2 - y1)^2);

        % Direction cosines
        c = (x2 - x1) / L;
        s = (y2 - y1) / L;

        % Element DOFs
        dof = [2*n1-1, 2*n1, 2*n2-1, 2*n2];

        % Element displacement vector
        ue = app.U(dof);

        % Axial strain
        strain = (1 / L) * [-c -s c s] * ue;

        % Axial stress
        stress = app.E * strain;

        % Store results
        app.ElementStrain(e) = strain;
        app.ElementStress(e) = stress;
    end

end 
function updateResults(app)

    % Maximum displacement magnitude
    ux = app.U(1:2:end);
    uy = app.U(2:2:end);
    app.MaxDisp = max(sqrt(ux.^2 + uy.^2));

    % Maximum absolute stress
    app.MaxStress = max(abs(app.ElementStress));

    % Compute total weight
    totalWeight = 0;

    for e = 1:size(app.Elements, 1)

        n1 = app.Elements(e, 1);
        n2 = app.Elements(e, 2);

        x1 = app.Nodes(n1, 1);
        y1 = app.Nodes(n1, 2);
        x2 = app.Nodes(n2, 1);
        y2 = app.Nodes(n2, 2);

        L = sqrt((x2 - x1)^2 + (y2 - y1)^2);

        totalWeight = totalWeight + app.Rho * app.A * L*9.81;
    end

    app.WeightValue = totalWeight;

    % Objective = W * delta at the loaded node
    if ~isempty(app.ForceBCs)
        loadNode = app.ForceBCs(1, 1);   % first force-applied node

        uxLoad = app.U(2*loadNode - 1);
        uyLoad = app.U(2*loadNode);

        deltaLoad = sqrt(uxLoad^2 + uyLoad^2);
    else
        deltaLoad = 0;
    end

    app.ObjectiveValue = app.WeightValue * deltaLoad;

    % Update GUI labels
   app.OptDispLabel.Text = sprintf('Max Disp: %.4g', app.MaxDisp);
app.OptStressLabel.Text = sprintf('Max Stress: %.4g', app.MaxStress);
app.OptWeightLabel.Text = sprintf('Weight: %.4g', app.WeightValue);
app.OptObjectiveLabel.Text = sprintf('W x delta: %.4g', app.ObjectiveValue);
    drawnow;

end
function redrawPostProcess(app)

    % Clear axes
    cla(app.StressAxes, 'reset');
    cla(app.DisplacementAxes, 'reset');

    hold(app.StressAxes, 'on');
    hold(app.DisplacementAxes, 'on');

    % Stop if no model
    if isempty(app.Nodes) || isempty(app.Elements)
        hold(app.StressAxes, 'off');
        hold(app.DisplacementAxes, 'off');
        return;
    end

    % --------------------------------------------------
    % Draw undeformed structure if enabled
    % --------------------------------------------------
    if strcmp(app.ShowUndeformedSwitch.Value, 'On')

        for e = 1:size(app.Elements, 1)

            n1 = app.Elements(e, 1);
            n2 = app.Elements(e, 2);

            x1 = app.Nodes(n1, 1);
            y1 = app.Nodes(n1, 2);
            x2 = app.Nodes(n2, 1);
            y2 = app.Nodes(n2, 2);

            plot(app.StressAxes, [x1 x2], [y1 y2], 'Color', [0.6 0.6 0.6], 'Linestyle', ':', 'LineWidth', 1.5);
            plot(app.DisplacementAxes, [x1 x2], [y1 y2], 'Color', [0.6 0.6 0.6], 'LineStyle', ':', 'LineWidth', 1.2);
            %plot(app.DisplacementAxes, [x1 x2], [y1 y2], 'k-', 'LineWidth', 1.5);
        end

        % Plot undeformed nodes
        %plot(app.StressAxes, app.Nodes(:,1), app.Nodes(:,2), 'ko', ...
            %'MarkerFaceColor', 'k', 'MarkerSize', 5);

        %plot(app.DisplacementAxes, app.Nodes(:,1), app.Nodes(:,2), 'ko', ...
            %'MarkerFaceColor', 'k', 'MarkerSize', 5);
        plot(app.StressAxes, app.Nodes(:,1), app.Nodes(:,2), 'o', 'Color', [0.5 0.5 0.5], 'MarkerFaceColor', [0.5 0.5 0.5], 'MarkerSize', 4);
        plot(app.DisplacementAxes, app.Nodes(:,1), app.Nodes(:,2), 'o', 'Color', [0.5 0.5 0.5], 'MarkerFaceColor', [0.5 0.5 0.5], 'MarkerSize', 4);
    
      end

    % --------------------------------------------------
    % Node labels
    % --------------------------------------------------
    if strcmp(app.PPNodeLabelsSwitch.Value, 'On')
        for i = 1:size(app.Nodes, 1)

            text(app.StressAxes, app.Nodes(i,1), app.Nodes(i,2), num2str(i), ...
                'HorizontalAlignment', 'center', ...
                'VerticalAlignment', 'bottom', ...
                'FontSize', 12, 'Color', 'b');

            text(app.DisplacementAxes, app.Nodes(i,1), app.Nodes(i,2), num2str(i), ...
                'HorizontalAlignment', 'center', ...
                'VerticalAlignment', 'bottom', ...
                'FontSize', 12, 'Color', 'b');
        end
    end

    % --------------------------------------------------
    % Stress-colored structure
    % --------------------------------------------------
    if ~isempty(app.ElementStress)

        stresses = abs(app.ElementStress);

        cmapName = app.ColorMapDropDown.Value;
        cmap = colormap(app.StressAxes, cmapName);

        cmin = min(stresses);
        cmax = max(stresses);
        if cmin == cmax
            cmax = cmin + eps;
        end

        colormap(app.StressAxes, cmap);
        clim(app.StressAxes, [cmin cmax]);

        for e = 1:size(app.Elements, 1)

            n1 = app.Elements(e, 1);
            n2 = app.Elements(e, 2);

            x1 = app.Nodes(n1, 1);
            y1 = app.Nodes(n1, 2);
            x2 = app.Nodes(n2, 1);
            y2 = app.Nodes(n2, 2);

            sVal = stresses(e);
            colorIndex = round(1 + (size(cmap, 1) - 1) * (sVal - cmin) / (cmax - cmin));
            colorIndex = max(1, min(size(cmap, 1), colorIndex));
            lineColor = cmap(colorIndex, :);

            plot(app.StressAxes, [x1 x2], [y1 y2], '-', ...
                'Color', lineColor, 'LineWidth', 3);
        end

        colorbar(app.StressAxes);
    end

    % --------------------------------------------------
    % Deformed / displacement-colored structure
    % --------------------------------------------------
    if ~isempty(app.U)

        scale = app.ScaleSlider.Value;

        % Deformed coordinates
        x_def = app.Nodes(:,1) + scale * app.U(1:2:end);
        y_def = app.Nodes(:,2) + scale * app.U(2:2:end);

        % Choose result type for right plot
        switch app.ResultTypeDropDown.Value
            case 'Ux'
                nodeVals = app.U(1:2:end);
                useElementStress = false;
            case 'Uy'
                nodeVals = app.U(2:2:end);
                useElementStress = false;
            case '|U|'
                ux = app.U(1:2:end);
                uy = app.U(2:2:end);
                nodeVals = sqrt(ux.^2 + uy.^2);
                useElementStress = false;
            case 'Stress'
                elemVals = abs(app.ElementStress);
                useElementStress = true;
            otherwise
                ux = app.U(1:2:end);
                uy = app.U(2:2:end);
                nodeVals = sqrt(ux.^2 + uy.^2);
                useElementStress = false;
        end

        % Choose colormap
        cmapName = app.ColorMapDropDown.Value;
        cmap = colormap(app.DisplacementAxes, cmapName);

        if useElementStress
            % Stress mode: element-based coloring
            vmin = min(elemVals);
            vmax = max(elemVals);
            if vmin == vmax
                vmax = vmin + eps;
            end

            colormap(app.DisplacementAxes, cmap);
            caxis(app.DisplacementAxes, [vmin vmax]);

            for e = 1:size(app.Elements, 1)

                n1 = app.Elements(e, 1);
                n2 = app.Elements(e, 2);

                val = elemVals(e);
                colorIndex = round(1 + (size(cmap, 1) - 1) * (val - vmin) / (vmax - vmin));
                colorIndex = max(1, min(size(cmap, 1), colorIndex));
                lineColor = cmap(colorIndex, :);

                plot(app.DisplacementAxes, [x_def(n1) x_def(n2)], [y_def(n1) y_def(n2)], '-', ...
                    'Color', lineColor, 'LineWidth', 3);
            end

        else
            % Displacement mode: node-based coloring
            vmin = min(nodeVals);
            vmax = max(nodeVals);
            if vmin == vmax
                vmax = vmin + eps;
            end

            colormap(app.DisplacementAxes, cmap);
            caxis(app.DisplacementAxes, [vmin vmax]);

            for e = 1:size(app.Elements, 1)

                n1 = app.Elements(e, 1);
                n2 = app.Elements(e, 2);

                val = (nodeVals(n1) + nodeVals(n2)) / 2;
                colorIndex = round(1 + (size(cmap, 1) - 1) * (val - vmin) / (vmax - vmin));
                colorIndex = max(1, min(size(cmap, 1), colorIndex));
                lineColor = cmap(colorIndex, :);

                plot(app.DisplacementAxes, [x_def(n1) x_def(n2)], [y_def(n1) y_def(n2)], '-', ...
                    'Color', lineColor, 'LineWidth', 3);
            end
        end

        % Plot deformed nodes
        plot(app.DisplacementAxes, x_def, y_def, 'o', ...
            'MarkerFaceColor', 'k', 'MarkerSize', 5);

        colorbar(app.DisplacementAxes);
    end

    % --------------------------------------------------
    % Axis settings
    % --------------------------------------------------
    axis(app.StressAxes, 'equal');
    axis(app.DisplacementAxes, 'equal');

    grid(app.StressAxes, 'on');
    grid(app.DisplacementAxes, 'on');

    title(app.StressAxes, 'Stress');
    title(app.DisplacementAxes, 'Displacement');

    hold(app.StressAxes, 'off');
    hold(app.DisplacementAxes, 'off');

    drawnow;

end
    function redrawPlot(app)

    cla(app.UIAxes, 'reset');
    hold(app.UIAxes, 'on');

    % Draw elements
    if ~isempty(app.Nodes) && ~isempty(app.Elements)
        for i = 1:size(app.Elements,1)
            n1 = app.Elements(i,1);
            n2 = app.Elements(i,2);

            x1 = app.Nodes(n1,1);
            y1 = app.Nodes(n1,2);
            x2 = app.Nodes(n2,1);
            y2 = app.Nodes(n2,2);

            plot(app.UIAxes, [x1 x2], [y1 y2], 'k-', 'LineWidth', 2);

            if app.ShowElementLabels
                xm = (x1 + x2) / 2;
                ym = (y1 + y2) / 2;

                text(app.UIAxes, xm, ym, num2str(i), ...
                    'HorizontalAlignment', 'center', ...
                    'VerticalAlignment', 'middle', ...
                    'FontWeight', 'bold', ...
                    'FontSize', 10, ...
                    'Color', 'black', ...
                    'BackgroundColor', 'yellow', ...
                    'EdgeColor', 'black', ...
                    'Margin', 2);
            end
        end
    end

    % Draw nodes
    if ~isempty(app.Nodes)
        plot(app.UIAxes, app.Nodes(:,1), app.Nodes(:,2), 'o', ...
            'MarkerSize', 10, ...
            'MarkerFaceColor', 'b', ...
            'MarkerEdgeColor', 'b');

        if app.ShowNodeLabels
            for i = 1:size(app.Nodes,1)
                text(app.UIAxes, app.Nodes(i,1), app.Nodes(i,2), num2str(i), ...
                    'HorizontalAlignment', 'center', ...
                    'VerticalAlignment', 'middle', ...
                    'Color', 'w', ...
                    'FontWeight', 'bold', ...
                    'FontSize', 10);
            end
        end
    end

    hold(app.UIAxes, 'off');
    axis(app.UIAxes, 'equal');
    grid(app.UIAxes, 'on');

    drawnow;

end

        
    % --- FİTNESS (HESAPLAMA) FONKSİYONU ---
    function f = kopruFitnessHesapla(app, x)

    n_nodes = size(app.Nodes, 1);
    n_elements = size(app.Elements, 1);

    % Default penalty
    f = 1e10;

    if isempty(app.Nodes) || isempty(app.Elements)
        return;
    end

    % x = فقط node koordinatları
    if numel(x) ~= 2 * n_nodes
        return;
    end

    % Yeni node koordinatları
    new_nodes = zeros(n_nodes, 2);
    for i = 1:n_nodes
        new_nodes(i, 1) = x(2*i - 1);
        new_nodes(i, 2) = x(2*i);
    end

    elements = app.Elements;
    rho = app.Rho;
    E = app.E;
    A = app.A;

    % Tüm elemanlar aktif kabul edilir
    W = 0;

    % Global stiffness matrix
    K = zeros(2 * n_nodes);

    for i = 1:n_elements
        n1 = elements(i, 1);
        n2 = elements(i, 2);

        x1 = new_nodes(n1, 1);
        y1 = new_nodes(n1, 2);
        x2 = new_nodes(n2, 1);
        y2 = new_nodes(n2, 2);

        L = sqrt((x2 - x1)^2 + (y2 - y1)^2);

        % Geçersiz / çok kısa eleman kontrolü
        if L <= 0
            return;
        end

        % İstersen bu sınır kalsın
        if L > 3
            return;
        end

        c = (x2 - x1) / L;
        s = (y2 - y1) / L;

        k_local = (E * A / L) * [ ...
            c^2,   c*s,  -c^2,  -c*s;
            c*s,   s^2,  -c*s,  -s^2;
           -c^2,  -c*s,   c^2,   c*s;
           -c*s,  -s^2,   c*s,   s^2 ];

        idx = [2*n1-1, 2*n1, 2*n2-1, 2*n2];
        K(idx, idx) = K(idx, idx) + k_local;

        W = W + rho * A * L;
    end

    % Displacement BC
    fixed_dofs = [];
    fixed_vals = [];

    if ~isempty(app.DispBCs)
        for i = 1:size(app.DispBCs, 1)
            node = app.DispBCs(i, 1);
            dir  = app.DispBCs(i, 2);   % 1 = X, 2 = Y
            val  = app.DispBCs(i, 3);

            dof = 2 * node - (2 - dir);

            fixed_dofs(end+1) = dof; %#ok<AGROW>
            fixed_vals(end+1) = val; %#ok<AGROW>
        end
    end

    free_dofs = setdiff(1:(2 * n_nodes), fixed_dofs);

    if isempty(free_dofs)
        return;
    end

    % Force vector
    F = zeros(2 * n_nodes, 1);
    loaded_dof = [];

    if ~isempty(app.ForceBCs)
        for i = 1:size(app.ForceBCs, 1)
            node = app.ForceBCs(i, 1);
            dir  = app.ForceBCs(i, 2);   % 1 = X, 2 = Y
            val  = app.ForceBCs(i, 3);

            dof = 2 * node - (2 - dir);
            F(dof) = F(dof) + val;

            if isempty(loaded_dof)
                loaded_dof = dof;
            end
        end
    else
        return;
    end

    K_reduced = K(free_dofs, free_dofs);
    F_reduced = F(free_dofs);

    % Prescribed displacement etkisi
    if ~isempty(fixed_dofs)
        F_reduced = F_reduced - K(free_dofs, fixed_dofs) * fixed_vals(:);
    end

    % Singular matrix check
    if isempty(K_reduced) || rcond(K_reduced) < 1e-12 || isnan(rcond(K_reduced))
        return;
    end

    % Solve
    U_reduced = K_reduced \ F_reduced;

    app.U = zeros(2 * n_nodes, 1);
    app.U(free_dofs) = U_reduced;
    app.U(fixed_dofs) = fixed_vals(:);

    % Element strain/stress
    app.ElementStrain = zeros(n_elements, 1);
    app.ElementStress = zeros(n_elements, 1);

    for i = 1:n_elements
        n1 = elements(i, 1);
        n2 = elements(i, 2);

        x1 = new_nodes(n1, 1);
        y1 = new_nodes(n1, 2);
        x2 = new_nodes(n2, 1);
        y2 = new_nodes(n2, 2);

        L = sqrt((x2 - x1)^2 + (y2 - y1)^2);

        if L <= 0
            return;
        end

        c = (x2 - x1) / L;
        s = (y2 - y1) / L;

        dof = [2*n1-1, 2*n1, 2*n2-1, 2*n2];
        ue = app.U(dof);

        strain = (1 / L) * [-c -s c s] * ue;
        stress = E * strain;

        app.ElementStrain(i) = strain;
        app.ElementStress(i) = stress;
    end

    % Max displacement
    ux = app.U(1:2:end);
    uy = app.U(2:2:end);
    dispMag = sqrt(ux.^2 + uy.^2);
    app.MaxDisp = max(dispMag);

    % Max stress
    app.MaxStress = max(abs(app.ElementStress));

    % Weight
    app.WeightValue = W;

    % Objective = W * delta at first loaded dof
    if isempty(loaded_dof)
        return;
    end

    delta = abs(app.U(loaded_dof));
    app.ObjectiveValue = W * delta;

    f = app.ObjectiveValue;

end  
            
            % --- ÇİZİM FONKSİYONU ---
 function kopruyuCiz(app, x)

    n_nodes = size(app.Nodes, 1);
    elements = app.Elements;

    % Yeni node koordinatları
    yeni_nodes = zeros(n_nodes, 2);
    for i = 1:n_nodes
        yeni_nodes(i, 1) = x(2*i - 1);
        yeni_nodes(i, 2) = x(2*i);
    end

    cla(app.OptAxes, 'reset');
    hold(app.OptAxes, 'on');

    % Tüm elemanları çiz
    for i = 1:size(elements, 1)
        n1 = elements(i, 1);
        n2 = elements(i, 2);

        X_coords = [yeni_nodes(n1, 1), yeni_nodes(n2, 1)];
        Y_coords = [yeni_nodes(n1, 2), yeni_nodes(n2, 2)];

        plot(app.OptAxes, X_coords, Y_coords, 'b-', 'LineWidth', 2.5, ...
            'Marker', 'o', 'MarkerEdgeColor', 'k', ...
            'MarkerFaceColor', 'y', 'MarkerSize', 6);
    end

    % Düğümler
    plot(app.OptAxes, yeni_nodes(:,1), yeni_nodes(:,2), 'ko', ...
        'MarkerFaceColor', 'y', 'MarkerSize', 6);

    hold(app.OptAxes, 'off');
    axis(app.OptAxes, 'equal');
    grid(app.OptAxes, 'on');
    title(app.OptAxes, 'Optimized Bridge');

end

function bridgeStress(app, x)

    n_nodes = size(app.Nodes, 1);
    elements = app.Elements;
    n_elements = size(elements, 1);

    % Yeni node koordinatları
    new_nodes = zeros(n_nodes, 2);
    for i = 1:n_nodes
        new_nodes(i,1) = x(2*i - 1);
        new_nodes(i,2) = x(2*i);
    end

    cla(app.OptStressAxes, 'reset');
    hold(app.OptStressAxes, 'on');

    if isempty(app.ElementStress)
        hold(app.OptStressAxes, 'off');
        return;
    end

    stresses = abs(app.ElementStress);

    cmap = colormap(app.OptStressAxes, app.ColorMapDropDown.Value);

    cmin = min(stresses);
    cmax = max(stresses);
    if cmin == cmax
        cmax = cmin + eps;
    end

    colormap(app.OptStressAxes, cmap);
    clim(app.OptStressAxes, [cmin cmax]);

    for i = 1:n_elements
        n1 = elements(i,1);
        n2 = elements(i,2);

        sVal = stresses(i);

        colorIndex = round(1 + (size(cmap,1)-1) * (sVal - cmin) / (cmax - cmin));
        colorIndex = max(1, min(size(cmap,1), colorIndex));

        lineColor = cmap(colorIndex,:);

        plot(app.OptStressAxes, ...
            [new_nodes(n1,1) new_nodes(n2,1)], ...
            [new_nodes(n1,2) new_nodes(n2,2)], ...
            '-', 'Color', lineColor, 'LineWidth', 3);
    end

    plot(app.OptStressAxes, new_nodes(:,1), new_nodes(:,2), 'ko', ...
        'MarkerFaceColor', 'y', 'MarkerSize', 6);

    axis(app.OptStressAxes, 'equal');
    grid(app.OptStressAxes, 'on');
    title(app.OptStressAxes, 'Live Stress');
    colorbar(app.OptStressAxes);

    hold(app.OptStressAxes, 'off');

end
        
    % --- GA ÇIKTI FONKSİYONU (Her iterasyonda tetiklenir) ---
    function [state, options, optchanged] = gaOutputFunc(app, options, state, flag)
    optchanged = false;

    if strcmp(flag, 'iter')
        if app.StopRequested
            state.StopFlag = 'Stopped by user';
            return;
        end

        % Current best individual in this generation
        [currentBest, bestIdx] = min(state.Score);
        current_best_x = state.Population(bestIdx, :);

        % Update iteration info
        app.IterationLabel.Text = sprintf('Iteration: %d', state.Generation);
        app.BestScoreLabel.Text = sprintf('Best Score: %.4g', currentBest);

        % Check improvement
        if isfinite(app.LastBestScore)
            improvement = abs(app.LastBestScore - currentBest) / max(abs(app.LastBestScore), eps);

            if improvement < app.StopTolerance
                app.NoImproveCount = app.NoImproveCount + 1;
            else
                app.NoImproveCount = 0;
            end
        end

        % Save latest best score
        app.LastBestScore = currentBest;

        % Recompute results for the current best design
        app.kopruFitnessHesapla(current_best_x);

        % Update plots
        app.kopruyuCiz(current_best_x);
        app.bridgeStress(current_best_x);

        % Update live result labels
        app.OptWeightLabel.Text = sprintf('Weight: %.4g', app.WeightValue);
        app.OptDispLabel.Text = sprintf('Max Disp: %.4g', app.MaxDisp);
        app.OptStressLabel.Text = sprintf('Max Stress: %.4g', app.MaxStress);
        app.OptObjectiveLabel.Text = sprintf('W x delta: %.4g', app.ObjectiveValue);

        drawnow;

        % Stop when improvement stays below threshold
        if state.Generation >= app.MinGenerations && app.NoImproveCount >= app.StallGenerations
            state.StopFlag = 'Objective improvement below threshold';
        end
    end


   end
        
   function tabloyuGuncelle(app, varargin)

    if isempty(app.Nodes)
        return;
    end

    n_nodes = size(app.Nodes, 1);
    tableData = cell(n_nodes, 13);

    % Varsayılanlar
    hesaplamaVarMi = false;
    reactionVarMi = false;

    % 1. argüman: U matrisi
    if nargin >= 2 && ~isempty(varargin{1})
        U_sonuc = varargin{1};
        hesaplamaVarMi = true;
    end

    % 2. argüman: Reaction kuvvetleri
    if nargin >= 3 && ~isempty(varargin{2})
        R_sonuc = varargin{2};
        reactionVarMi = true;
    end

    free_counter = 1;
    fixed_counter = -1;

    for i = 1:n_nodes
        tableData{i, 1} = num2str(i);
        tableData{i, 2} = num2str(app.Nodes(i, 1));
        tableData{i, 3} = num2str(app.Nodes(i, 2));

        bc_x = 'N';
        bc_y = 'N';

        disp_x = '?';
        disp_y = '?';

        force_x = '0';
        force_y = '0';

        % Displacement BC kontrolü
        if ~isempty(app.DispBCs)
            if any(app.DispBCs(:,1) == i & app.DispBCs(:,2) == 1)
                bc_x = 'D';
                disp_x = '0';
                force_x = '?';
            end

            if any(app.DispBCs(:,1) == i & app.DispBCs(:,2) == 2)
                bc_y = 'D';
                disp_y = '0';
                force_y = '?';
            end
        end

        % Uygulanan kuvvetleri yaz
        if ~isempty(app.ForceBCs)
            idx_x = find(app.ForceBCs(:,1) == i & app.ForceBCs(:,2) == 1, 1);
            if ~isempty(idx_x) && bc_x == 'N'
                force_x = num2str(app.ForceBCs(idx_x, 3), '%.5f');
            end

            idx_y = find(app.ForceBCs(:,1) == i & app.ForceBCs(:,2) == 2, 1);
            if ~isempty(idx_y) && bc_y == 'N'
                force_y = num2str(app.ForceBCs(idx_y, 3), '%.5f');
            end
        end

        % Eğer çözüm yapıldıysa displacement değerlerini yaz
        if hesaplamaVarMi && length(U_sonuc) >= 2*i
            disp_x = num2str(U_sonuc(2*i - 1), '%.5f');
            disp_y = num2str(U_sonuc(2*i), '%.5f');
        end

        % Eğer reaction hesaplandıysa, sabit DOF'larda onu yaz
        if reactionVarMi
            if bc_x == 'D'
                force_x = num2str(R_sonuc(2*i - 1), '%.5f');
            end
            if bc_y == 'D'
                force_y = num2str(R_sonuc(2*i), '%.5f');
            end
        end

        tableData{i, 4} = bc_x;
        tableData{i, 5} = bc_y;

        if bc_x == 'N'
            tableData{i, 6} = num2str(free_counter);
            free_counter = free_counter + 1;
        else
            tableData{i, 6} = num2str(fixed_counter);
            fixed_counter = fixed_counter - 1;
        end

        if bc_y == 'N'
            tableData{i, 7} = num2str(free_counter);
            free_counter = free_counter + 1;
        else
            tableData{i, 7} = num2str(fixed_counter);
            fixed_counter = fixed_counter - 1;
        end

        tableData{i, 8} = num2str(2*i - 1);
        tableData{i, 9} = num2str(2*i);

        tableData{i, 10} = disp_x;
        tableData{i, 11} = disp_y;
        tableData{i, 12} = force_x;
        tableData{i, 13} = force_y;
    end

    app.ExtendedNodeTable.Data = tableData;
end
        
        function cizimiGuncelle(app)
            % 1. Clear only the drawn objects (Lines, Patches, Scatters, Texts)
            % Using 'delete' instead of 'cla' preserves the UI Axes grid and colors
            % 1. GÜVENLİK KONTROLÜ: Uygulama veya eksen silinmişse işlemi durdur
            if ~isvalid(app) || ~isvalid(app.UIAxes)
                return;
            end
        
            % 2. EKSENİ TEMİZLE: Eski 'delete' yerine 'cla' kullanıyoruz
            cla(app.UIAxes);
        
            hold(app.UIAxes, 'on');
            grid(app.UIAxes, 'on'); 
            
            % Exit if there are no nodes
            if isempty(app.Nodes)
                return;
            end
            
            % Calculate bounding box and center points
            max_x = max(app.Nodes(:,1)); min_x = min(app.Nodes(:,1));
            max_y = max(app.Nodes(:,2)); min_y = min(app.Nodes(:,2));
            
            % GEOMETRIC CENTER (To determine inside vs outside for supports/forces)
            center_x = (min_x + max_x) / 2;
            center_y = (min_y + max_y) / 2;
            
            % Span calculation to scale drawing proportions
            span = max(max_x - min_x, max_y - min_y);
            if span == 0, span = 1; end
            a = span * 0.05; % Scale factor for symbols
            
            % 2. DRAW THE ELEMENTS AND ELEMENT LABELS
            if ~isempty(app.Elements)
                drawn_labels = []; 
                
                for i = 1:size(app.Elements, 1)
                    n1 = app.Elements(i, 1); n2 = app.Elements(i, 2);
                    x = [app.Nodes(n1, 1), app.Nodes(n2, 1)];
                    y = [app.Nodes(n1, 2), app.Nodes(n2, 2)];
                    
                    % Siyah çubuğu çiz
                    plot(app.UIAxes, x, y, 'Color', [0 1 1], 'LineWidth', 2);
                    
                    % ELEMENT LABELS SWITCH KONTROLÜ
                    if strcmp(app.ElementLabelsSwitch.Value, 'On')
                        
                        % Çubuğun X ve Y eksenlerindeki uzunlukları (Eğim için)
                        dx = x(2) - x(1);
                        dy = y(2) - y(1);
                        
                        % --- SMART GEOMETRIC PLACEMENT (Zeki Konumlandırma) ---
                        % Eğer çubuk çapraz ise (X ve Y'de değişimi varsa)
                        if abs(dx) > 1e-5 && abs(dy) > 1e-5
                            % Eğimin yönüne göre (Sağa yatık vs Sola yatık) etiketleri zıt uçlara çek
                            if dx * dy > 0
                                % Sağa yatık (/) çubuklar -> Etiketi %70 (Bir uca) noktasına koy
                                mid_x = x(1)*0.30 + x(2)*0.70;
                                mid_y = y(1)*0.30 + y(2)*0.70;
                            else
                                % Sola yatık (\) çubuklar -> Etiketi %30 (Diğer uca) noktasına koy
                                mid_x = x(1)*0.70 + x(2)*0.30;
                                mid_y = y(1)*0.70 + y(2)*0.30;
                            end
                        else
                            % Yatay ve Dikey çubuklarda tam merkeze (%50) koy
                            mid_x = (x(1) + x(2)) / 2;
                            mid_y = (y(1) + y(2)) / 2;
                        end
                        
                        % --- SECONDARY COLLISION AVOIDANCE (İkincil Güvenlik) ---
                        % Ola ki yatay çubuklarda hala çok yakınlaşan olursa, çubuk doğrultusunda kaydır
                        L_elem = sqrt(dx^2 + dy^2);
                        if L_elem == 0, ux = 1; uy = 0; else, ux = dx/L_elem; uy = dy/L_elem; end
                        
                        if ~isempty(drawn_labels)
                            dists = sqrt((drawn_labels(:,1) - mid_x).^2 + (drawn_labels(:,2) - mid_y).^2);
                            shift_step = a * 0.35; 
                            
                            while any(dists < a * 0.4)
                                mid_x = mid_x + ux * shift_step;
                                mid_y = mid_y + uy * shift_step;
                                dists = sqrt((drawn_labels(:,1) - mid_x).^2 + (drawn_labels(:,2) - mid_y).^2);
                            end
                        end
                        
                        % Etiketi Ekrana Çiz
                        text(app.UIAxes, mid_x, mid_y, num2str(i), 'Color', 'k', 'FontSize', 10, 'BackgroundColor', 'y', 'EdgeColor', 'k', 'HorizontalAlignment', 'center');
                        
                        % Çizilen noktanın koordinatını kaydet
                        drawn_labels = [drawn_labels; mid_x, mid_y];
                    end
                end
            end
            
            % 3. DRAW THE NODES AND NODE LABELS
            if strcmp(app.NodeLabelsSwitch.Value, 'On')
                % If ON: Large Blue Circles with White Numbers
                plot(app.UIAxes, app.Nodes(:,1), app.Nodes(:,2), 'bo', 'MarkerSize', 14, 'MarkerFaceColor', 'b');
                for i = 1:size(app.Nodes, 1)
                    text(app.UIAxes, app.Nodes(i,1), app.Nodes(i,2), num2str(i), 'Color', 'w', 'FontSize', 9, 'FontWeight', 'bold', 'HorizontalAlignment', 'center');
                end
            else
                % If OFF: Standard small black dots
                plot(app.UIAxes, app.Nodes(:,1), app.Nodes(:,2), 'ko', 'MarkerSize', 8, 'MarkerFaceColor', 'b');
            end
            
            % 4. DRAW SUPPORTS & FORCES (Controlled by Switch2)
            if strcmp(app.DisplayBCSwitch.Value, 'On')
                
                % --- DRAW BOUNDARY CONDITIONS (SUPPORTS) ---
                if ~isempty(app.DispBCs)
                    for i = 1:size(app.Nodes, 1)
                        x = app.Nodes(i, 1); y = app.Nodes(i, 2);
                        
                        % Dynamic Direction Control (Draw supports outwards)
                        dir_y = -1; if y > center_y, dir_y = 1; end
                        dir_x = -1; if x > center_x, dir_x = 1; end
                        
                        % GÜNCELLEME: Şarta "Değeri 0 olmalı" kuralı eklendi
                        isFixedX = any(app.DispBCs(:,1) == i & app.DispBCs(:,2) == 1 & app.DispBCs(:,3) == 0);
                        isFixedY = any(app.DispBCs(:,1) == i & app.DispBCs(:,2) == 2 & app.DispBCs(:,3) == 0);
                        
                        if isFixedX && isFixedY
                            % PINNED SUPPORT (Red Triangle, Gray Base)
                            patch(app.UIAxes, [x, x-a, x+a], [y, y + dir_y*a*1.5, y + dir_y*a*1.5], 'white', 'EdgeColor', 'r', 'LineWidth', 1.5);
                            patch(app.UIAxes, [x-a*1.5, x+a*1.5, x+a*1.5, x-a*1.5], ...
                                              [y + dir_y*a*1.5, y + dir_y*a*1.5, y + dir_y*a*1.8, y + dir_y*a*1.8], ...
                                              [0.7 0.7 0.7], 'EdgeColor', 'k', 'LineWidth', 1.5);
                                              
                        elseif isFixedY && ~isFixedX
                            % HORIZONTAL ROLLER (Red Triangle, Red Wheels, Black Ground)
                            patch(app.UIAxes, [x, x-a, x+a], [y, y + dir_y*a*1.5, y + dir_y*a*1.5], 'white', 'EdgeColor', 'r', 'LineWidth', 1.5);
                            plot(app.UIAxes, [x-a/2, x+a/2], [y + dir_y*a*1.75, y + dir_y*a*1.75], 'ro', 'MarkerFaceColor', 'r', 'MarkerSize', 5, 'LineWidth', 1.5);
                            plot(app.UIAxes, [x-a*1.5, x+a*1.5], [y + dir_y*a*2, y + dir_y*a*2], 'k', 'LineWidth', 2);
                            
                        elseif isFixedX && ~isFixedY
                            % VERTICAL ROLLER (Red Triangle, Red Wheels, Black Wall)
                            patch(app.UIAxes, [x, x + dir_x*a*1.5, x + dir_x*a*1.5], [y, y+a, y-a], 'white', 'EdgeColor', 'r', 'LineWidth', 1.5);
                            plot(app.UIAxes, [x + dir_x*a*1.75, x + dir_x*a*1.75], [y+a/2, y-a/2], 'ro', 'MarkerFaceColor', 'r', 'MarkerSize', 5, 'LineWidth', 1.5);
                            plot(app.UIAxes, [x + dir_x*a*2, x + dir_x*a*2], [y+a*1.5, y-a*1.5], 'k', 'LineWidth', 2);
                        end
                    end
                end
                % BÖLÜM B: DEĞERİ SIFIR OLMAYANLARI SİYAH OKLA ÇİZ (PRESCRIBED DISPLACEMENT)
                    % DispBCs matrisinden 3. sütunu (Value) 0 olmayanları filtrele
                    non_zero_disp = app.DispBCs(app.DispBCs(:,3) ~= 0, :);
                    if ~isempty(non_zero_disp)
                        max_d = max(abs(non_zero_disp(:,3))); 
                        if max_d == 0, max_d = 1; end
                        
                        for i = 1:size(non_zero_disp, 1)
                            node_idx = non_zero_disp(i, 1);
                            dir = non_zero_disp(i, 2);
                            val = non_zero_disp(i, 3);
                            
                            x = app.Nodes(node_idx, 1); y = app.Nodes(node_idx, 2);
                            
                            % Ok boyunu değerin büyüklüğüne göre ayarla
                            arrow_len = a * 2 * (abs(val) / max_d); 
                            if arrow_len < a, arrow_len = a; end
                            txt_offset = a * 0.5; disp_txt = num2str(val); 
                            
                            if dir == 1 % X-Direction
                                if x >= center_x 
                                    if val > 0 
                                        quiver(app.UIAxes, x, y, arrow_len, 0, 0, 'b', 'LineWidth', 2, 'MaxHeadSize', 0.5);
                                    else       
                                        quiver(app.UIAxes, x + arrow_len, y, -arrow_len, 0, 0, 'b', 'LineWidth', 2, 'MaxHeadSize', 0.5);
                                    end
                                    text(app.UIAxes, x + arrow_len + txt_offset, y, disp_txt, 'Color', 'b', 'FontSize', 11, 'FontWeight', 'bold', 'HorizontalAlignment', 'left');
                                else 
                                    if val > 0 
                                        quiver(app.UIAxes, x - arrow_len, y, arrow_len, 0, 0, 'b', 'LineWidth', 2, 'MaxHeadSize', 0.5);
                                    else       
                                        quiver(app.UIAxes, x, y, -arrow_len, 0, 0, 'b', 'LineWidth', 2, 'MaxHeadSize', 0.5);
                                    end
                                    text(app.UIAxes, x - arrow_len - txt_offset, y, disp_txt, 'Color', 'b', 'FontSize', 11, 'FontWeight', 'bold', 'HorizontalAlignment', 'right');
                                end
                            elseif dir == 2 % Y-Direction
                                if y >= center_y 
                                    if val > 0 
                                        quiver(app.UIAxes, x, y, 0, arrow_len, 0, 'b', 'LineWidth', 2, 'MaxHeadSize', 0.5);
                                    else       
                                        quiver(app.UIAxes, x, y + arrow_len, 0, -arrow_len, 0, 'b', 'LineWidth', 2, 'MaxHeadSize', 0.5);
                                    end
                                    text(app.UIAxes, x, y + arrow_len + txt_offset, disp_txt, 'Color', 'b', 'FontSize', 11, 'FontWeight', 'bold', 'HorizontalAlignment', 'center', 'VerticalAlignment', 'bottom');
                                else 
                                    if val > 0 
                                        quiver(app.UIAxes, x, y - arrow_len, 0, arrow_len, 0, 'b', 'LineWidth', 2, 'MaxHeadSize', 0.5);
                                    else       
                                        quiver(app.UIAxes, x, y, 0, -arrow_len, 0, 'b', 'LineWidth', 2, 'MaxHeadSize', 0.5);
                                    end
                                    text(app.UIAxes, x, y - arrow_len - txt_offset, disp_txt, 'Color', 'b', 'FontSize', 11, 'FontWeight', 'bold', 'HorizontalAlignment', 'center', 'VerticalAlignment', 'top');
                                end
                            end
                        end
                    end


                % --- DRAW FORCES WITH TEXT LABELS ---
                if ~isempty(app.ForceBCs)
                    max_f = max(abs(app.ForceBCs(:,3))); 
                    if max_f == 0, max_f = 1; end
                    
                    for i = 1:size(app.ForceBCs, 1)
                        node_idx = app.ForceBCs(i, 1);
                        dir = app.ForceBCs(i, 2);
                        val = app.ForceBCs(i, 3);
                        
                        x = app.Nodes(node_idx, 1); y = app.Nodes(node_idx, 2);
                        
                        % Scale arrow length based on force magnitude
                        arrow_len = a * 2 * (abs(val) / max_f); 
                        if arrow_len < a, arrow_len = a; end
                        txt_offset = a * 0.5; force_txt = num2str(val); 
                        
                        if dir == 1 % X-Direction
                            if x >= center_x % Node is on the Right half
                                if val > 0 % Pulling right
                                    quiver(app.UIAxes, x, y, arrow_len, 0, 0, 'r', 'LineWidth', 2, 'MaxHeadSize', 0.5);
                                else       % Pushing left
                                    quiver(app.UIAxes, x + arrow_len, y, -arrow_len, 0, 0, 'r', 'LineWidth', 2, 'MaxHeadSize', 0.5);
                                end
                                text(app.UIAxes, x + arrow_len + txt_offset, y, force_txt, 'Color', 'r', 'FontSize', 11, 'FontWeight', 'bold', 'HorizontalAlignment', 'left');
                            else % Node is on the Left half
                                if val > 0 % Pushing right
                                    quiver(app.UIAxes, x - arrow_len, y, arrow_len, 0, 0, 'r', 'LineWidth', 2, 'MaxHeadSize', 0.5);
                                else       % Pulling left
                                    quiver(app.UIAxes, x, y, -arrow_len, 0, 0, 'r', 'LineWidth', 2, 'MaxHeadSize', 0.5);
                                end
                                text(app.UIAxes, x - arrow_len - txt_offset, y, force_txt, 'Color', 'r', 'FontSize', 11, 'FontWeight', 'bold', 'HorizontalAlignment', 'right');
                            end
                        elseif dir == 2 % Y-Direction
                            if y >= center_y % Node is on the Upper half
                                if val > 0 % Pulling up
                                    quiver(app.UIAxes, x, y, 0, arrow_len, 0, 'r', 'LineWidth', 2, 'MaxHeadSize', 0.5);
                                else       % Pushing down
                                    quiver(app.UIAxes, x, y + arrow_len, 0, -arrow_len, 0, 'r', 'LineWidth', 2, 'MaxHeadSize', 0.5);
                                end
                                text(app.UIAxes, x, y + arrow_len + txt_offset, force_txt, 'Color', 'r', 'FontSize', 11, 'FontWeight', 'bold', 'HorizontalAlignment', 'center', 'VerticalAlignment', 'bottom');
                            else % Node is on the Lower half
                                if val > 0 % Pushing up
                                    quiver(app.UIAxes, x, y - arrow_len, 0, arrow_len, 0, 'r', 'LineWidth', 2, 'MaxHeadSize', 0.5);
                                else       % Pulling down
                                    quiver(app.UIAxes, x, y, 0, -arrow_len, 0, 'r', 'LineWidth', 2, 'MaxHeadSize', 0.5);
                                end
                                text(app.UIAxes, x, y - arrow_len - txt_offset, force_txt, 'Color', 'r', 'FontSize', 11, 'FontWeight', 'bold', 'HorizontalAlignment', 'center', 'VerticalAlignment', 'top');
                            end
                        end
                    end
                end
            end 
            
            % Adjust and close the axes
            axis(app.UIAxes, 'equal');
            margin = span * 0.20; % Margin to fit all labels and supports
            axis(app.UIAxes, [min_x - margin, max_x + margin, min_y - margin, max_y + margin]);
            hold(app.UIAxes, 'off');
        end
end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
% Read materials from text file
fileName = "Materials.txt";

if ~isfile(fileName)
    uialert(app.UIFigure, ...
        'Materials.txt file was not found in the current folder.', ...
        'File Error');
    return;
end

lines = readlines(fileName);

materials = struct();
names = strings(0,1);

for i = 1:numel(lines)

    line = strtrim(lines(i));

    % Skip empty lines
    if line == ""
        continue;
    end

    parts = split(line, ",");

    % Skip malformed lines
    if numel(parts) < 3
        continue;
    end

    name = strtrim(parts(1));
    E_GPa = str2double(strtrim(parts(2)));
    rho   = str2double(strtrim(parts(3)));

    % Skip invalid numeric lines
    if isnan(E_GPa) || isnan(rho)
        continue;
    end

    validName = matlab.lang.makeValidName(name);

    % Store in SI units
    materials.(validName).E = E_GPa * 1e9;   % GPa -> Pa
    materials.(validName).Rho = rho;

    names(end+1) = name;
end

% Save database to app
app.MaterialDB = materials;

% Show labels in engineering units
app.EGPaLabel.Text = 'E (GPa)';
app.Acm2Label.Text = 'A (cm^2)';

% Fill dropdown automatically and add Custom
if ~isempty(names)
    items = [cellstr(names(:)); {'Custom'}];
    app.MaterialDropDown.Items = items;
    app.MaterialDropDown.Value = items{1};

    % Load first material into fields
    firstName = matlab.lang.makeValidName(names(1));
    app.EGPaEditField.Value   = app.MaterialDB.(firstName).E / 1e9;
    app.Acm2EditField.Value   = 1;   % 1 cm^2
    app.Rhokgm3EditField.Value = app.MaterialDB.(firstName).Rho;
else
    app.MaterialDropDown.Items = {'Custom'};
    app.MaterialDropDown.Value = 'Custom';
    app.EGPaEditField.Value   = 0;
    app.Acm2EditField.Value   = 0;
    app.Rhokgm3EditField.Value = 0;
    app.PreProcessPanel.BorderType = 'none';
app.BCPanel.BorderType = 'none';
app.MaterialPanel.BorderType = 'none';
app.PostProcessPanel.BorderType = 'none';
end
% RESET butonunu gizle
if isprop(app, 'ResetOptButton') && ~isempty(app.ResetOptButton)
    app.ResetOptButton.Visible = 'off';
    app.ResetOptButton.Enable = 'off';
end
app.BestScoreLabel.Visible = 'off';
app.ResultsPanel.Visible = 'off';
app.ResetDispButton.Visible = 'off';
app.ResetDispButton.Visible = 'off';
app.ResetStressButton.Visible = 'off';
app.ResetStressButton.Visible = 'off';
app.DisplayDropDown.Visible = 'off';
app.OptLamp.Visible = 'off';
        end

        % Button pushed function: GenerateNodeButton
        function GenerateNodeButtonPushed(app, event)
    

    x = app.XEditField.Value;
    y = app.YEditField.Value;

    % Check if nodes already exist
    if ~isempty(app.Nodes)
        
        % Check for duplicate coordinates (with tolerance)
        isDuplicate = any( abs(app.Nodes(:,1) - x) < 1e-6 & ...
                           abs(app.Nodes(:,2) - y) < 1e-6 );

        if isDuplicate
            uialert(app.UIFigure, ...
                'This node already exists!', ...
                'Duplicate Node');
            return;
        end
    end

    % Add new node
    app.Nodes = [app.Nodes; x y];

    % Update node count
    app.EditField.Value = size(app.Nodes, 1);

    % Redraw plot
    app.cizimiGuncelle();
    %redrawPlot(app);


        end

        % Button pushed function: GenerateElementButton
        function GenerateElementButtonPushed(app, event)

    % Get node indices from input fields
    n1 = app.EndNode1EditField.Value;
    n2 = app.EndNode2EditField.Value;

    % Basic validation
    if isempty(app.Nodes) || size(app.Nodes,1) < 2
        uialert(app.UIFigure, 'Please create at least 2 nodes first.', 'Error');
        return;
    end

    if n1 < 1 || n2 < 1 || ...
       n1 > size(app.Nodes,1) || n2 > size(app.Nodes,1)
        uialert(app.UIFigure, 'Invalid node number', 'Error');
        return;
    end

    if n1 == n2
        uialert(app.UIFigure, 'Cannot connect a node to itself', 'Error');
        return;
    end

    % Check if element already exists (n1-n2 or n2-n1)
    if ~isempty(app.Elements)
        isDuplicate = any( (app.Elements(:,1) == n1 & app.Elements(:,2) == n2) | ...
                           (app.Elements(:,1) == n2 & app.Elements(:,2) == n1) );

        if isDuplicate
            uialert(app.UIFigure, 'This element already exists!', 'Duplicate Element');
            return;
        end
    end

    % Compute element length
    x1 = app.Nodes(n1, 1);
    y1 = app.Nodes(n1, 2);
    x2 = app.Nodes(n2, 1);
    y2 = app.Nodes(n2, 2);

    L = sqrt((x2 - x1)^2 + (y2 - y1)^2);

    % Check element length constraint (<= 3 m)
    if L > 3
        uialert(app.UIFigure, ...
            'Element length cannot exceed 3 meters (bridge constraint).', ...
            'Invalid Element');
        return;
    end

    % Add new element
    app.Elements = [app.Elements; n1 n2];

    % Update element count
    app.EditField2.Value = size(app.Elements, 1);

    % Redraw plot
    %redrawPlot(app);
    app.cizimiGuncelle();


        end

        % Button pushed function: RemoveAllButton
        function RemoveAllButtonPushed(app, event)
            % Data reset
app.Nodes = [];
app.Elements = [];

% Switch reset
app.ShowNodeLabels = false;
app.ShowElementLabels = false;

app.NodeLabelsSwitch.Value = 'Off';
app.ElementLabelsSwitch.Value = 'Off';

% Counters reset
app.EditField.Value = 0;
app.EditField2.Value = 0;
app.cizimiGuncelle();
%redrawPlot(app);
        end

        % Value changed function: NodeLabelsSwitch
        function NodeLabelsSwitchValueChanged(app, event)
           if isvalid(app) && ~isempty(app.Nodes) 
            app.cizimiGuncelle();
           end 
        end

        % Value changed function: ElementLabelsSwitch
        function ElementLabelsSwitchValueChanged(app, event)
            
            app.cizimiGuncelle();
        end

        % Button pushed function: ReadDataButton
        function ReadDataButtonPushed(app, event)

    [file, path] = uigetfile({'*.txt','Text Files (*.txt)'}, ...
                             'Select Node and Element List File');
    if isequal(file, 0)
        return; % user cancelled
    end

    fullName = fullfile(path, file);
    lines = readlines(fullName);

    % Remove empty lines
    lines = strtrim(lines);
    lines(lines == "") = [];

    % Find headers
    nodeStart = find(strcmpi(lines, "NODES"), 1);
    elemStart = find(strcmpi(lines, "ELEMENTS"), 1);

    if isempty(nodeStart) || isempty(elemStart) || elemStart <= nodeStart
        uialert(app.UIFigure, ...
            'File must contain NODES and ELEMENTS sections in that order.', ...
            'File Format Error');
        return;
    end

    % Parse nodes
    nodeLines = lines(nodeStart+1 : elemStart-1);
    nodes = zeros(numel(nodeLines), 2);

    for i = 1:numel(nodeLines)
        vals = sscanf(nodeLines(i), '%f');
        if numel(vals) ~= 2
            uialert(app.UIFigure, ...
                'Each node line must contain exactly 2 numbers: x y', ...
                'File Format Error');
            return;
        end
        nodes(i,:) = vals(:).';
    end

    % Parse elements
    elemLines = lines(elemStart+1 : end);
    elems = zeros(numel(elemLines), 2);

    for i = 1:numel(elemLines)
        vals = sscanf(elemLines(i), '%f');
        if numel(vals) ~= 2
            uialert(app.UIFigure, ...
                'Each element line must contain exactly 2 node numbers: n1 n2', ...
                'File Format Error');
            return;
        end
        elems(i,:) = vals(:).';
    end

    % Validate element node indices
    if ~isempty(elems)
        if any(elems(:) < 1) || any(elems(:) > size(nodes,1))
            uialert(app.UIFigure, ...
                'Element indices must refer to existing nodes.', ...
                'File Format Error');
            return;
        end
    end

    % Replace current data
    app.Nodes = nodes;
    app.Elements = elems;

    % Update counters
    app.EditField.Value = size(app.Nodes, 1);
    app.EditField2.Value = size(app.Elements, 1);

    % Redraw with current switch states
    %redrawPlot(app);
    app.cizimiGuncelle();


        end

        % Button pushed function: ApplyBCButton
        function ApplyBCButtonPushed(app, event)
             % Read inputs
    node = app.BCNodeEditField.Value;
    dirStr = app.DirectionDropDown.Value;
    typeStr = app.BCTypeDropDown.Value;
    value = app.BCValueEditField.Value;

    % Basic validation
    if isempty(app.Nodes)
        uialert(app.UIFigure, 'Please create nodes first.', 'Error');
        return;
    end

    if node < 1 || node > size(app.Nodes, 1)
        uialert(app.UIFigure, 'Invalid node number.', 'Error');
        return;
    end

    if ~(strcmp(dirStr, 'X') || strcmp(dirStr, 'Y'))
        uialert(app.UIFigure, 'Direction must be X or Y.', 'Error');
        return;
    end

    if ~(strcmp(typeStr, 'Force') || strcmp(typeStr, 'Displacement'))
        uialert(app.UIFigure, 'Type must be Force or Displacement.', 'Error');
        return;
    end

    % Prevent duplicate BC on same node-direction pair
    if ~isempty(app.BCData)
        sameDOF = cellfun(@(n,d) n == node && strcmp(d, dirStr), ...
            app.BCData(:,1), app.BCData(:,3));

        if any(sameDOF)
            uialert(app.UIFigure, ...
                'This node and direction already have a boundary condition.', ...
                'Error');
            return;
        end
    end

    % Add row to table
    newRow = {int32(node), typeStr, dirStr, value};
    app.BCData = [app.BCData; newRow];
    app.BCTable.Data = app.BCData;

    % Save for solver
    dirNum = 1;
    if strcmp(dirStr, 'Y')
        dirNum = 2;
    end

    if strcmp(typeStr, 'Force')
        app.ForceBCs = [app.ForceBCs; node, dirNum, value];
    else
        app.DispBCs = [app.DispBCs; node, dirNum, value];
    end
   
        end

        % Button pushed function: ClearBCButton
        function ClearBCButtonPushed(app, event)
          % Clear stored BC data
    app.BCData = cell(0,4);
    app.ForceBCs = [];
    app.DispBCs = [];

    % Clear table
    app.BCTable.Data = cell(0,4);

    % Reset BC input fields
    app.BCNodeEditField.Value = 0;
    app.DirectionDropDown.Value = 'X';
    app.BCTypeDropDown.Value = 'Force';
    app.BCValueEditField.Value = 0;
      
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
    % Read material values
    app.E = app.EGPaEditField.Value* 1e9;
    app.A = app.Acm2EditField.Value* 1e-4;  % cm^2 → m^2
    app.Rho = app.Rhokgm3EditField.Value;

    % Validation
    if app.E <= 0
        uialert(app.UIFigure, 'E must be positive.', 'Error');
        return;
    end

    if app.A <= 0
        uialert(app.UIFigure, 'A must be positive.', 'Error');
        return;
    end

    if isempty(app.Nodes) || isempty(app.Elements)
        uialert(app.UIFigure, 'Create nodes and elements first.', 'Error');
        return;
    end

    if isempty(app.BCData)
        uialert(app.UIFigure, 'Apply at least one boundary condition.', 'Error');
        return;
    end

    % Run lamp (running)
    app.RunLamp.Color = [1 1 0];
    drawnow;

    disp("RUN STARTED");
    disp("E = " + app.E);
    disp("A = " + app.A);

    % Solve FEM
    solveTruss(app);

    % Compute element results
    computeElementResults(app);

    % Update output values
    updateResults(app);

    % Redraw post-process plots
    redrawPostProcess(app);

    % Run lamp (done)
    app.RunLamp.Color = [0 1 0];
    drawnow;
    app.tabloyuGuncelle(app.U, app.Reactions);

        end

        % Value changed function: ResultTypeDropDown
        function ResultTypeDropDownValueChanged(app, event)
            % Slider değerini anlık olarak al
            %app.ScaleSlider.Value = event.Value; 
            % Çizimi anında güncelle ki slider hareket ettikçe köprü de hareket etsin
            app.redrawPostProcess();
            %value = app.ResultTypeDropDown.Value;
            %redrawPostProcess(app);
        end

        % Value changed function: ScaleSlider
        function ScaleSliderValueChanged(app, event)
            %value = app.ScaleSlider.Value;
            %redrawPostProcess(app);
            app.redrawPostProcess();
        end

        % Value changed function: ShowUndeformedSwitch
        function ShowUndeformedSwitchValueChanged(app, event)
            value = app.ShowUndeformedSwitch.Value;
            redrawPostProcess(app);
        end

        % Value changed function: ColorMapDropDown
        function ColorMapDropDownValueChanged(app, event)
            value = app.ColorMapDropDown.Value;
            redrawPostProcess(app);
        end

        % Value changed function: PPNodeLabelsSwitch
        function PPNodeLabelsSwitchValueChanged(app, event)
            value = app.PPNodeLabelsSwitch.Value;
            redrawPostProcess(app);
        end

        % Button pushed function: ResetStressButton
        function ResetStressButtonPushed(app, event)
            redrawPostProcess(app);
        end

        % Button pushed function: ResetDispButton
        function ResetDispButtonPushed(app, event)
            redrawPostProcess(app);
        end

        % Button pushed function: DeleteElementButton
        function DeleteElementButtonPushed(app, event)

    
    % Get the ID of the element to be deleted
    elemID = app.DeleteElementEditField.Value;
    
    % 1. VALIDATION: Check if the list is empty, or ID is invalid (or 0)
    if isempty(app.Elements) || elemID < 1 || elemID > size(app.Elements, 1)
        uialert(app.UIFigure, 'Please enter a valid Element ID.', 'Error');
        return;
    end
    
    % 2. DELETE THE ELEMENT ITSELF
    % Elements are stored as rows in app.Elements. 
    % Deleting a row automatically shifts the remaining elements up.
    % Since boundary conditions and forces are tied to Nodes, not Elements,
    % we don't need to do any cascading cleanup here!
    app.Elements(elemID, :) = [];
    
    % 3. REDRAW EVERYTHING
    app.cizimiGuncelle();
    
    % 4. UX FEATURE: RESET THE INPUT FIELD TO 0
    app.DeleteElementEditField.Value = 0;


        end

        % Callback function
        function DropDownValueChanged(app, event)
    material = app.MaterialDropDown.Value;
    validName = matlab.lang.makeValidName(material);

    app.EGPaEditField.Value = app.MaterialDB.(validName).E;
    app.Rho = app.MaterialDB.(validName).Rho;

    % Bridge condition: same cross-sectional area for all elements
    app.Acm2EditField.Value = 1e-4;
        end

        % Callback function
        function MaterialMaterialDropDownValueChanged(app, event)
    material = app.MaterialDropDown.Value;

    if strcmp(material, 'Custom')
        % Allow user to enter all values manually
        app.EGPaEditField.Value = 0;
        app.Acm2EditField.Value = 0;
        app.Rhokgm3EditField.Value = 0;
        return;
    end

    validName = matlab.lang.makeValidName(material);

    app.EGPaEditField.Value = app.MaterialDB.(validName).E/ 1e9;
    app.Acm2EditField.Value = 1e-4;   % bridge cross-section
    app.Rhokgm3EditField.Value = app.MaterialDB.(validName).Rho;

        end

        % Value changed function: MaterialDropDown
        function MaterialDropDownValueChanged(app, event)
material = app.MaterialDropDown.Value;

% If user selects Custom
if strcmp(material, 'Custom')
    % Clear fields so user can enter values manually
    app.EGPaEditField.Value = 0;
    app.Acm2EditField.Value = 0;
    app.Rhokgm3EditField.Value = 0;
    return;
end

% Otherwise use database
validName = matlab.lang.makeValidName(material);

app.EGPaEditField.Value = app.MaterialDB.(validName).E / 1e9;
app.Acm2EditField.Value = 1;  % cm^2
app.Rhokgm3EditField.Value = app.MaterialDB.(validName).Rho;
            
        end

        % Button pushed function: OptimizeButton
        function OptimizeButtonPushed(app, event)
    % Reset live counters and labels
    app.StopRequested = false;
    app.IsOptimizing = true;
    app.OptLamp.Color = [1 1 0];   % sarı
    app.LastBestScore = inf;
    app.NoImproveCount = 0;
    app.IterationLabel.Text = 'Iteration: 0';
    app.BestScoreLabel.Text = 'Best Score: 0';
    app.OptDispLabel.Text = '0';
    app.OptStressLabel.Text = '0';
    app.WeightValueLabel.Text = '0';
    app.ObjectiveValueLabel.Text = '0';
    drawnow;

    if isempty(app.Nodes) || isempty(app.Elements)
        uialert(app.UIFigure, 'Please define nodes and elements first!', 'error');
        return;
    end

    n_nodes = size(app.Nodes, 1);

    % Sadece node koordinatları optimize edilecek:
    % Her node için X ve Y -> toplam 2*n_nodes değişken
    nvars = 2 * n_nodes;

    % Maximum allowed movement of nodes
    hareket_siniri = 0.5;

    lb = zeros(1, nvars);
    ub = zeros(1, nvars);

    % Fixed nodes: support nodes and load nodes
    if isempty(app.DispBCs)
        disp_nodes = [];
    else
        disp_nodes = app.DispBCs(:,1);
    end

    if isempty(app.ForceBCs)
        force_nodes = [];
    else
        force_nodes = app.ForceBCs(:,1);
    end

    sabit_dugumler = unique([disp_nodes; force_nodes]);

    % Node bounds
    for i = 1:n_nodes
        idxX = 2*i - 1;
        idxY = 2*i;

        if ismember(i, sabit_dugumler)
            % Sabit düğümler hiç hareket etmesin
            lb(idxX) = app.Nodes(i, 1);
            ub(idxX) = app.Nodes(i, 1);
            lb(idxY) = app.Nodes(i, 2);
            ub(idxY) = app.Nodes(i, 2);
        else
            % Diğer düğümler hareket edebilsin
            lb(idxX) = app.Nodes(i, 1) - hareket_siniri;
            ub(idxX) = app.Nodes(i, 1) + hareket_siniri;

            lb(idxY) = app.Nodes(i, 2) - hareket_siniri;
            ub(idxY) = app.Nodes(i, 2) + hareket_siniri;
        end
    end

    % GA options
    options = optimoptions('ga', ...
        'Display', 'iter', ...
        'OutputFcn', @(options, state, flag) app.gaOutputFunc(options, state, flag));

    ObjectiveFunction = @(x) app.kopruFitnessHesapla(x);

    % Run genetic algorithm
    [best_x, best_fval] = ga(ObjectiveFunction, nvars, [], [], [], [], lb, ub, [], options);

    % Final draw
    app.kopruyuCiz(best_x);
    app.bridgeStress(best_x);

    % Final objective label
    app.OptObjectiveLabel.Text = ['W x δ: ', num2str(best_fval, '%.4f')];


        end

        % Button pushed function: RunModalAnalysisButton_2
        function RunModalAnalysisButton_2Pushed(app, event)
            % 1. Kullanıcı veri girmemişse veya düğüm yoksa kodu durdur
    if isempty(app.Nodes) || isempty(app.Elements)
        uialert(app.UIFigure, 'Please create nodes and elements first.', 'Model is incomplete');
        return;
    end

    % --- TABLO BAŞLIKLARINI DÜZELTME ---
    % Design View'da değişmeyen "Column 4" yazısını burada zorla güncelliyoruz
    % --- TABLO BAŞLIKLARINI DÜZELTME ---
    % Tabloyu baştan aşağı zorla formatla
    app.ModalTable.ColumnName = {'Mode', 'Eigenvalue', 'Freq (Hz)', 'Eigenvector Info'};
    app.ModalTable.Data = {}; % İçini bir anlık boşalt ki başlıklar tazelensin
    

    % 2. Sayısal verileri UI'dan al (E: GPa->Pa, A: cm^2->m^2)
    E_val = app.EGPaEditField.Value * 1e9; 
    A_val = app.Acm2EditField.Value * 1e-4; 
    rho_val = app.Rhokgm3EditField.Value;    
    
    nNodes = size(app.Nodes, 1);
    ndof = 2 * nNodes;
    K = zeros(ndof, ndof);
    M = zeros(ndof, ndof);
    
    % 3. Stiffness (K) ve Mass (M) matrislerini monte et (Assembly)
    % 3. Stiffness (K) ve Mass (M) matrislerini monte et (Assembly)
    for e = 1:size(app.Elements, 1)
        n1 = app.Elements(e, 1); n2 = app.Elements(e, 2);
        x1 = app.Nodes(n1, 1); y1 = app.Nodes(n1, 2);
        x2 = app.Nodes(n2, 1); y2 = app.Nodes(n2, 2);
        L = sqrt((x2-x1)^2 + (y2-y1)^2);
        
        % --- CONSISTENT MASS MATRIX ---
        m_coeff = (rho_val * A_val * L) / 6;
        
        % 4x4 matrisi köşeli parantezlerle [ ] hatasız tanımlıyoruz
        me = m_coeff * [2, 0, 1, 0; 
                        0, 2, 0, 1; 
                        1, 0, 2, 0; 
                        0, 1, 0, 2];
        
        % Global kütle matrisine ekleme (Assembly)
        dofs = [2*n1-1, 2*n1, 2*n2-1, 2*n2];
        for i = 1:4
            for j = 1:4
                M(dofs(i), dofs(j)) = M(dofs(i), dofs(j)) + me(i, j);
            end
        end
        
        % Stiffness Matrix
        c = (x2-x1)/L; s = (y2-y1)/L;
        ke = (E_val * A_val / L) * [c*c c*s -c*c -c*s; 
                                     c*s s*s -c*s -s*s; 
                                    -c*c -c*s c*c c*s; 
                                    -c*s -s*s c*s s*s];
        K(dofs, dofs) = K(dofs, dofs) + ke;
    end % Bu end, 'for e' döngüsünü kapatır [cite: 1374]


    % 4. Sınır Koşullarını (BC) Uygula
    fixedDofs = [];
    if ~isempty(app.DispBCs)
        for i = 1:size(app.DispBCs, 1)
            node = app.DispBCs(i, 1); dir = app.DispBCs(i, 2); 
            fixedDofs(end+1) = 2*node - (2 - dir);
        end
    end
    allDofs = 1:ndof;
    freeDofs = setdiff(allDofs, fixedDofs);
    if isempty(freeDofs), return; end

    % 5. ÖZDEĞER ÇÖZÜCÜ (Eig Solver)
    [V, D] = eig(K(freeDofs, freeDofs), M(freeDofs, freeDofs));
    
    % 6. Spinner'dan seçilen modu al ve kontrol et
    selectedMode = app.ModeSpinner.Value;
    numAvailableModes = size(V, 2);
    if selectedMode > numAvailableModes
        selectedMode = numAvailableModes;
        app.ModeSpinner.Value = selectedMode; 
    end
    
    % Seçilen modun şeklini (eigenvector) hazırla
    modeShape = zeros(ndof, 1);
    modeShape(freeDofs) = V(:, selectedMode); 
    visualScale = 1.2; 
    
    % 7. Çizim (app.ModalAxes)
    cla(app.ModalAxes); 
    hold(app.ModalAxes, 'on');
    for e = 1:size(app.Elements, 1)
        n1 = app.Elements(e, 1); n2 = app.Elements(e, 2);
        x1 = app.Nodes(n1, 1); y1 = app.Nodes(n1, 2);
        x2 = app.Nodes(n2, 1); y2 = app.Nodes(n2, 2);
        nx1 = x1 + visualScale * modeShape(2*n1-1);
        ny1 = y1 + visualScale * modeShape(2*n1);
        nx2 = x2 + visualScale * modeShape(2*n2-1);
        ny2 = y2 + visualScale * modeShape(2*n2);
        
        plot(app.ModalAxes, [x1 x2], [y1 y2], 'k:', 'LineWidth', 0.8); % Orijinal
        plot(app.ModalAxes, [nx1 nx2], [ny1 ny2], 'r-o', 'LineWidth', 2.5, 'MarkerFaceColor', 'r'); % Titreşim
    end
    title(app.ModalAxes, ['Bridge Dynamics: Mode Shape #' num2str(selectedMode)]);
    grid(app.ModalAxes, 'on'); axis(app.ModalAxes, 'equal');
    xlabel(app.ModalAxes, 'X (m)'); ylabel(app.ModalAxes, 'Y (m)');
    
    % 8. Tabloyu Doldur (Eigenvalue & Frequency & Vector Info)
    % 8. Tabloyu Doldur (Kaç DOF varsa o kadar satır gösterir)
    lambdas = diag(D); 
    frequencies = sqrt(lambdas) / (2 * pi);
    
    % Dinamik satır sayısı: Kaç tane frekans hesaplandıysa o kadar satır olsun
    numToShow = length(frequencies); 
    tableData = cell(numToShow, 4);
    
    for i = 1:numToShow
        tableData{i, 1} = i;                             % Mode No
        tableData{i, 2} = sprintf('%.4f', lambdas(i));    % Eigenvalue
        tableData{i, 3} = sprintf('%.2f Hz', frequencies(i)); % Frekans
        
        % Vektör Bilgisi: En çok hareket eden serbestlik derecesi (DOF)
        [~, maxIdx] = max(abs(V(:, i)));
        tableData{i, 4} = ['Max at DOF ' num2str(freeDofs(maxIdx))];
    end
    
    % Veriyi tabloya bas
    app.ModalTable.Data = tableData;
    % ALTTAKİ 1833 ve 1834 olmazsa sil gif gibi canlı için

    app.V = V;
    app.freeDofs = freeDofs;
    
    % Grafik alanını "serbest bırak" (Eski hold off burada durabilir)
    hold(app.ModalAxes, 'off');
        end

        % Button pushed function: PlayModeSoundButton
        function PlayModeSoundButtonPushed(app, event)
            % 1. Veri kontrolü (Analiz yapılmadan basılırsa hata vermesin)
    if isempty(app.ModalTable.Data) || isempty(app.V)
        uialert(app.UIFigure, 'Please run Modal Analysis first!', 'No Modal Data');
        return;
    end
    
    % 2. Spinner değerini al ve limit kontrolü yap
    selectedMode = round(app.ModeSpinner.Value);
    maxAvailable = size(app.V, 2); % Hesaplanan toplam mod sayısı
    
    if selectedMode > maxAvailable
        uialert(app.UIFigure, ['The system only has ' num2str(maxAvailable) ' modes. Please select a valid mode.'], 'Invalid Mode');
        app.ModeSpinner.Value = maxAvailable; % Otomatik düzelt
        return;
    end
    
    % 3. Ses Sinyali Oluşturma (Mevcut kodun devamı)
    freqStr = app.ModalTable.Data{selectedMode, 3}; 
    f_real = str2double(extractBefore(freqStr, ' Hz')); 
    fs = 44100; t = 0:1/fs:1.2;
    audible_f = f_real * 5; 
    while audible_f < 120, audible_f = audible_f * 2; end
    damping_factor = 2.5 + (selectedMode / 4);
    y = exp(-damping_factor * t) .* sin(2 * pi * audible_f * t);
    sound(y, fs);
        end

        % Button pushed function: AnimateButton
        function AnimateButtonPushed(app, event)
    % 1. Analiz ve Veri Kontrolü
        if isempty(app.Nodes) || isempty(app.V)
            uialert(app.UIFigure, 'Modal results not found. Please click "Run Modal Analysis" first.', 'Analysis Required');
            return;
        end
        
        % 2. Mod Sayısı Limit Kontrolü
        selectedMode = round(app.ModeSpinner.Value);
        maxAvailable = size(app.V, 2);
        
        if selectedMode > maxAvailable
            uialert(app.UIFigure, ['Cannot animate mode ' num2str(selectedMode) '. Maximum available mode is ' num2str(maxAvailable) '.'], 'Mode Out of Range');
            app.ModeSpinner.Value = maxAvailable;
            return;
        end
        
        % 3. Animasyon Parametreleri (Mevcut döngün)
        nNodes = size(app.Nodes, 1);
        ndof = 2 * nNodes;
        frames = 30; t = linspace(0, 2*pi, frames); scale = 0.8;
        modeShape = zeros(ndof, 1);
        modeShape(app.freeDofs) = app.V(:, selectedMode);
        
        for k = 1:length(t)
            cla(app.ModalAxes); hold(app.ModalAxes, 'on');
            current_scale = scale * sin(t(k));
            for e = 1:size(app.Elements, 1)
                n1 = app.Elements(e, 1); n2 = app.Elements(e, 2);
                x1 = app.Nodes(n1, 1); y1 = app.Nodes(n1, 2);
                x2 = app.Nodes(n2, 1); y2 = app.Nodes(n2, 2);
                nx1 = x1 + current_scale * modeShape(2*n1-1);
                ny1 = y1 + current_scale * modeShape(2*n1);
                nx2 = x2 + current_scale * modeShape(2*n2-1);
                ny2 = y2 + current_scale * modeShape(2*n2);
                plot(app.ModalAxes, [x1 x2], [y1 y2], 'k:', 'LineWidth', 0.5);
                plot(app.ModalAxes, [nx1 nx2], [ny1 ny2], 'r-o', 'LineWidth', 2, 'MarkerFaceColor', 'r');
            end
            freqVal = app.ModalTable.Data{selectedMode, 3};
            title(app.ModalAxes, {['Dynamic Oscillation: Mode #', num2str(selectedMode)], ['Natural Frequency: ', freqVal]}, 'Color', 'w', 'FontSize', 12);
            axis(app.ModalAxes, 'equal'); grid(app.ModalAxes, 'on');
            drawnow;
    end % 1. for döngüsünü kapatır 
        end

        % Callback function
        function Switch2ValueChanged(app, event)
           app.cizimiGuncelle();
            
        end

        % Value changed function: DisplayBCSwitch
        function DisplayBCSwitchPushed(app, event)
            value = app.DisplayBCSwitch.Value;
            app.cizimiGuncelle();
            
        end

        % Button pushed function: DeleteNodeButton
        function DeleteNodeButtonPushed(app, event)
            % Get the ID of the node to be deleted
            nodeID = app.DeleteNodeEditField.Value;
            
            % 1. VALIDATION: Check if the list is empty, or ID is invalid (or 0)
            if isempty(app.Nodes) || nodeID < 1 || nodeID > size(app.Nodes, 1)
                uialert(app.UIFigure, 'Please enter a valid Node ID.', 'Error');
                return;
            end
            
            % 2. CLEANUP ELEMENTS: Remove connected elements and shift indices
            if ~isempty(app.Elements)
                % Find elements connected to this node
                connectedElems = (app.Elements(:,1) == nodeID) | (app.Elements(:,2) == nodeID);
                app.Elements(connectedElems, :) = []; 
                % Shift remaining elements
                app.Elements(app.Elements > nodeID) = app.Elements(app.Elements > nodeID) - 1;
            end
            
            % 3. CLEANUP SUPPORTS: Remove and shift indices
            if ~isempty(app.DispBCs)
                app.DispBCs(app.DispBCs(:,1) == nodeID, :) = [];
                if ~isempty(app.DispBCs)
                    app.DispBCs(app.DispBCs(:,1) > nodeID, 1) = app.DispBCs(app.DispBCs(:,1) > nodeID, 1) - 1;
                end
            end
            
            % 4. CLEANUP FORCES: Remove and shift indices
            if ~isempty(app.ForceBCs)
                app.ForceBCs(app.ForceBCs(:,1) == nodeID, :) = [];
                if ~isempty(app.ForceBCs)
                    app.ForceBCs(app.ForceBCs(:,1) > nodeID, 1) = app.ForceBCs(app.ForceBCs(:,1) > nodeID, 1) - 1;
                end
            end
            
            % 5. DELETE THE NODE ITSELF
            app.Nodes(nodeID, :) = [];
            
            % 6. REDRAW EVERYTHING
            app.cizimiGuncelle();
            
            % 7. UX FEATURE: RESET THE INPUT FIELD TO 0
            app.DeleteNodeEditField.Value = 0;
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
    app.StopRequested = true;
    app.IsOptimizing = false;
    app.OptLamp.Color = [1 0 0];   % kırmızı
    drawnow;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PreProcessandProcessTab = uitab(app.TabGroup);
            app.PreProcessandProcessTab.Title = 'Pre-Process and Process';
            app.PreProcessPanel = uipanel(app.PreProcessandProcessTab);
            app.PreProcessPanel.Title = 'Pre-Process';
            app.UIAxes = uiaxes(app.PreProcessPanel);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.NodesLabel = uilabel(app.PreProcessPanel);
            app.NodesLabel.Text = 'Nodes';
            app.XEditFieldLabel = uilabel(app.PreProcessPanel);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.PreProcessPanel, 'numeric');
            app.YEditFieldLabel = uilabel(app.PreProcessPanel);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.PreProcessPanel, 'numeric');
            app.GenerateNodeButton = uibutton(app.PreProcessPanel, 'push');
            app.GenerateNodeButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateNodeButtonPushed, true);
            app.GenerateNodeButton.Text = 'Generate Node';
            app.ElementsLabel = uilabel(app.PreProcessPanel);
            app.ElementsLabel.Text = 'Elements';
            app.EndNode1EditFieldLabel = uilabel(app.PreProcessPanel);
            app.EndNode1EditFieldLabel.Text = 'End Node 1';
            app.EndNode1EditField = uieditfield(app.PreProcessPanel, 'numeric');
            app.EndNode2EditFieldLabel = uilabel(app.PreProcessPanel);
            app.EndNode2EditFieldLabel.Text = 'End Node 2';
            app.EndNode2EditField = uieditfield(app.PreProcessPanel, 'numeric');
            app.GenerateElementButton = uibutton(app.PreProcessPanel, 'push');
            app.GenerateElementButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateElementButtonPushed, true);
            app.GenerateElementButton.Text = 'Generate Element';
            app.ReadDataButton = uibutton(app.PreProcessPanel, 'push');
            app.ReadDataButton.ButtonPushedFcn = createCallbackFcn(app, @ReadDataButtonPushed, true);
            app.ReadDataButton.Text = 'Read Node and Element List';
            app.NodesLabel_2 = uilabel(app.PreProcessPanel);
            app.NodesLabel_2.Text = '#Nodes';
            app.ElementsLabel_2 = uilabel(app.PreProcessPanel);
            app.ElementsLabel_2.Text = '#Elements';
            app.EditField = uieditfield(app.PreProcessPanel, 'numeric');
            app.EditField2 = uieditfield(app.PreProcessPanel, 'numeric');
            app.RemoveAllButton = uibutton(app.PreProcessPanel, 'push');
            app.RemoveAllButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveAllButtonPushed, true);
            app.RemoveAllButton.Text = 'Remove All';
            app.NodeLabelsSwitchLabel = uilabel(app.PreProcessPanel);
            app.NodeLabelsSwitchLabel.Text = 'Node Labels';
            app.NodeLabelsSwitch = uiswitch(app.PreProcessPanel, 'slider');
            app.NodeLabelsSwitch.ValueChangedFcn = createCallbackFcn(app, @NodeLabelsSwitchValueChanged, true);
            app.ElementLabelsSwitchLabel = uilabel(app.PreProcessPanel);
            app.ElementLabelsSwitchLabel.Text = 'Element Labels';
            app.ElementLabelsSwitch = uiswitch(app.PreProcessPanel, 'slider');
            app.ElementLabelsSwitch.ValueChangedFcn = createCallbackFcn(app, @ElementLabelsSwitchValueChanged, true);
            app.DeleteElementEditField = uieditfield(app.PreProcessPanel, 'numeric');
            app.DeleteElementEditField.Limits = [0 Inf];
            app.DeleteElementButton = uibutton(app.PreProcessPanel, 'push');
            app.DeleteElementButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteElementButtonPushed, true);
            app.DeleteElementButton.Text = 'Delete Element';
            app.DisplayBCSwitchLabel = uilabel(app.PreProcessPanel);
            app.DisplayBCSwitchLabel.Text = 'Display BC';
            app.DisplayBCSwitch = uiswitch(app.PreProcessPanel, 'rocker');
            app.DisplayBCSwitch.ValueChangedFcn = createCallbackFcn(app, @DisplayBCSwitchPushed, true);
            app.DeleteNodeButton = uibutton(app.PreProcessPanel, 'push');
            app.DeleteNodeButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteNodeButtonPushed, true);
            app.DeleteNodeButton.Text = 'Delete Node';
            app.DeleteNodeEditField = uieditfield(app.PreProcessPanel, 'numeric');
            app.DeleteNodeEditField.Limits = [0 Inf];
            app.ProcessPanel = uipanel(app.PreProcessandProcessTab);
            app.ProcessPanel.Title = 'Process';
            app.BCPanel = uipanel(app.ProcessPanel);
            app.BCPanel.Title = 'Boundary Conditions';
            app.NodeEditFieldLabel = uilabel(app.BCPanel);
            app.NodeEditFieldLabel.Text = 'Node';
            app.BCNodeEditField = uieditfield(app.BCPanel, 'numeric');
            app.DirectionLabel = uilabel(app.BCPanel);
            app.DirectionLabel.Text = 'Direction';
            app.DirectionDropDown = uidropdown(app.BCPanel);
            app.DirectionDropDown.Items = {'X', 'Y'};
            app.DirectionDropDown.Value = 'X';
            app.BCTypeDropDownLabel = uilabel(app.BCPanel);
            app.BCTypeDropDownLabel.Text = 'BC Type';
            app.BCTypeDropDown = uidropdown(app.BCPanel);
            app.BCTypeDropDown.Items = {'Force', 'Displacement'};
            app.BCTypeDropDown.Value = 'Force';
            app.ValueEditFieldLabel = uilabel(app.BCPanel);
            app.ValueEditFieldLabel.Text = 'Value';
            app.BCValueEditField = uieditfield(app.BCPanel, 'numeric');
            app.ApplyBCButton = uibutton(app.BCPanel, 'push');
            app.ApplyBCButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyBCButtonPushed, true);
            app.ApplyBCButton.Text = 'APPLY';
            app.BCTable = uitable(app.BCPanel);
            app.BCTable.ColumnName = {'Node'; 'Type'; 'Dir'; 'Value'};
            app.BCTable.RowName = {};
            app.ClearBCButton = uibutton(app.BCPanel, 'push');
            app.ClearBCButton.ButtonPushedFcn = createCallbackFcn(app, @ClearBCButtonPushed, true);
            app.ClearBCButton.Text = 'CLEAR ALL';
            app.MaterialPanel = uipanel(app.ProcessPanel);
            app.MaterialPanel.Title = 'Material Properties';
            app.EGPaLabel = uilabel(app.MaterialPanel);
            app.EGPaLabel.Text = 'E (GPa)';
            app.EGPaEditField = uieditfield(app.MaterialPanel, 'numeric');
            app.Acm2Label = uilabel(app.MaterialPanel);
            app.Acm2Label.Text = 'A (cm^2)';
            app.Acm2EditField = uieditfield(app.MaterialPanel, 'numeric');
            app.Rhokgm3EditFieldLabel = uilabel(app.MaterialPanel);
            app.Rhokgm3EditFieldLabel.Text = 'Rho (kg/m^3)';
            app.Rhokgm3EditField = uieditfield(app.MaterialPanel, 'numeric');
            app.RunButton = uibutton(app.ProcessPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'RUN';
            app.RunLamp = uilamp(app.ProcessPanel);
            app.MaterialDropDown = uidropdown(app.ProcessPanel);
            app.MaterialDropDown.ValueChangedFcn = createCallbackFcn(app, @MaterialDropDownValueChanged, true);
            app.MaterialSelectionLabel = uilabel(app.ProcessPanel);
            app.MaterialSelectionLabel.Text = 'Material Selection';
            app.Results = uitab(app.TabGroup);
            app.Results.Title = 'Results';
            app.PostProcessPanel = uipanel(app.Results);
            app.PostProcessPanel.Title = 'Post-Process';
            app.DisplacementAxes = uiaxes(app.PostProcessPanel);
            title(app.DisplacementAxes, 'Displacement')
            xlabel(app.DisplacementAxes, 'X')
            ylabel(app.DisplacementAxes, 'Y')
            zlabel(app.DisplacementAxes, 'Z')
            app.StressAxes = uiaxes(app.PostProcessPanel);
            title(app.StressAxes, 'Stress')
            xlabel(app.StressAxes, 'X')
            ylabel(app.StressAxes, 'Y')
            zlabel(app.StressAxes, 'Z')
            app.ModalAxes = uiaxes(app.PostProcessPanel);
            title(app.ModalAxes, 'ModalAxes')
            xlabel(app.ModalAxes, 'X')
            ylabel(app.ModalAxes, 'Y')
            zlabel(app.ModalAxes, 'Z')
            app.PostControlPanel = uipanel(app.PostProcessPanel);
            app.PostControlPanel.Title = 'Controls';
            app.ResultTypeLabel = uilabel(app.PostControlPanel);
            app.ResultTypeLabel.Text = 'Result Type';
            app.ResultTypeDropDown = uidropdown(app.PostControlPanel);
            app.ResultTypeDropDown.Items = {'Ux', 'Uy', '|U|', 'Stress'};
            app.ResultTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @ResultTypeDropDownValueChanged, true);
            app.ResultTypeDropDown.Value = 'Ux';
            app.ScaleLabel = uilabel(app.PostControlPanel);
            app.ScaleLabel.Text = 'Scale';
            app.ScaleSlider = uislider(app.PostControlPanel);
            app.ScaleSlider.ValueChangedFcn = createCallbackFcn(app, @ScaleSliderValueChanged, true);
            app.ScaleSlider.Value = 10;
            app.ShowUndeformedLabel = uilabel(app.PostControlPanel);
            app.ShowUndeformedLabel.Text = 'Show Undeformed';
            app.ShowUndeformedSwitch = uiswitch(app.PostControlPanel, 'slider');
            app.ShowUndeformedSwitch.ValueChangedFcn = createCallbackFcn(app, @ShowUndeformedSwitchValueChanged, true);
            app.PPNodeLabelsLabel = uilabel(app.PostControlPanel);
            app.PPNodeLabelsLabel.Text = 'Node Labels';
            app.PPNodeLabelsSwitch = uiswitch(app.PostControlPanel, 'slider');
            app.PPNodeLabelsSwitch.ValueChangedFcn = createCallbackFcn(app, @PPNodeLabelsSwitchValueChanged, true);
            app.ColormapLabel = uilabel(app.PostControlPanel);
            app.ColormapLabel.Text = 'Colormap';
            app.ColorMapDropDown = uidropdown(app.PostControlPanel);
            app.ColorMapDropDown.Items = {'parula', 'jet', 'turbo', 'gray'};
            app.ColorMapDropDown.ValueChangedFcn = createCallbackFcn(app, @ColorMapDropDownValueChanged, true);
            app.ColorMapDropDown.Value = 'parula';
            app.ResetStressButton = uibutton(app.PostProcessPanel, 'push');
            app.ResetStressButton.ButtonPushedFcn = createCallbackFcn(app, @ResetStressButtonPushed, true);
            app.ResetStressButton.Text = 'Reset';
            app.ResetDispButton = uibutton(app.PostProcessPanel, 'push');
            app.ResetDispButton.ButtonPushedFcn = createCallbackFcn(app, @ResetDispButtonPushed, true);
            app.ResetDispButton.Text = 'Reset';
            app.ModalTable = uitable(app.PostProcessPanel);
            app.ModalTable.ColumnName = {'Mode'; 'Eigenvalue'; 'Freq (Hz)'; 'Eigenvector Info'};
            app.ModalTable.RowName = {};
            app.ResultsPanel = uipanel(app.PostProcessPanel);
            app.ResultsPanel.Title = 'Results';
            app.WeightLabel = uilabel(app.ResultsPanel);
            app.WeightLabel.Text = 'Weight';
            app.WeightValueLabel = uilabel(app.ResultsPanel);
            app.WeightValueLabel.Text = '0';
            app.ObjectiveLabel = uilabel(app.ResultsPanel);
            app.ObjectiveLabel.Text = 'W × δ';
            app.ObjectiveValueLabel = uilabel(app.ResultsPanel);
            app.ObjectiveValueLabel.Text = '0';
            app.RunModalAnalysisButton_2 = uibutton(app.PostProcessPanel, 'push');
            app.RunModalAnalysisButton_2.ButtonPushedFcn = createCallbackFcn(app, @RunModalAnalysisButton_2Pushed, true);
            app.RunModalAnalysisButton_2.Text = 'Run Modal Analysis';
            app.ModeSpinnerLabel = uilabel(app.PostProcessPanel);
            app.ModeSpinnerLabel.Text = 'Mode';
            app.ModeSpinner = uispinner(app.PostProcessPanel);
            app.ModeSpinner.Limits = [1 100];
            app.ModeSpinner.RoundFractionalValues = 'on';
            app.ModeSpinner.Value = 1;
            app.PlayModeSoundButton = uibutton(app.PostProcessPanel, 'push');
            app.PlayModeSoundButton.ButtonPushedFcn = createCallbackFcn(app, @PlayModeSoundButtonPushed, true);
            app.PlayModeSoundButton.Text = 'Play Mode Sound';
            app.AnimateButton = uibutton(app.PostProcessPanel, 'push');
            app.AnimateButton.ButtonPushedFcn = createCallbackFcn(app, @AnimateButtonPushed, true);
            app.AnimateButton.Text = 'Animate';
            app.ExtendedNodeListTab = uitab(app.TabGroup);
            app.ExtendedNodeListTab.Title = 'Extended Node List';
            app.ExtendedNodeTable = uitable(app.ExtendedNodeListTab);
            app.ExtendedNodeTable.ColumnName = {'NL'; 'COOR X'; 'COOR Y'; 'BC X'; 'BC Y'; 'TMP X'; 'TMP Y'; 'GLOB X'; 'GLOB Y'; 'DISP X'; 'DISP Y'; 'FORCE X'; 'FORCE Y'};
            app.ExtendedNodeTable.RowName = {};
            app.extended = uilabel(app.ExtendedNodeListTab);
            app.extended.Text = 'Extended Node List';
            app.Optimization = uitab(app.TabGroup);
            app.Optimization.Title = 'Optimization';
            app.OptAxes = uiaxes(app.Optimization);
            title(app.OptAxes, 'Title')
            xlabel(app.OptAxes, 'X')
            ylabel(app.OptAxes, 'Y')
            zlabel(app.OptAxes, 'Z')
            app.OptStressAxes = uiaxes(app.Optimization);
            title(app.OptStressAxes, 'Title')
            xlabel(app.OptStressAxes, 'X')
            ylabel(app.OptStressAxes, 'Y')
            zlabel(app.OptStressAxes, 'Z')
            app.OptimizationControlLabel = uilabel(app.Optimization);
            app.OptimizationControlLabel.Text = 'Optimization Control';
            app.OptimizeButton = uibutton(app.Optimization, 'push');
            app.OptimizeButton.ButtonPushedFcn = createCallbackFcn(app, @OptimizeButtonPushed, true);
            app.OptimizeButton.Text = 'OPTIMIZE';
            app.StopButton = uibutton(app.Optimization, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'STOP';
            app.OptLamp = uilamp(app.Optimization);
            app.IterationLabel = uilabel(app.Optimization);
            app.IterationLabel.Text = 'Iteration: 0';
            app.BestScoreLabel = uilabel(app.Optimization);
            app.BestScoreLabel.Text = 'Best Score: 0';
            app.LiveResultsLabel = uilabel(app.Optimization);
            app.LiveResultsLabel.Text = 'Live Results';
            app.OptWeightLabel = uilabel(app.Optimization);
            app.OptWeightLabel.Text = 'Weight: 0';
            app.OptDispLabel = uilabel(app.Optimization);
            app.OptDispLabel.Text = 'Max Disp: 0';
            app.OptStressLabel = uilabel(app.Optimization);
            app.OptStressLabel.Text = 'Max Stress: 0';
            app.OptObjectiveLabel = uilabel(app.Optimization);
            app.OptObjectiveLabel.Text = 'W × δ: 0';
            app.DisplayDropDown = uidropdown(app.Optimization);
            app.DisplayDropDown.Items = {'Show Iterations', 'Show Best'};
            app.DisplayDropDown.Value = 'Show Iterations';
            app.NLabel = uilabel(app.Optimization);
            app.NLabel.Text = 'N';
            app.mLabel = uilabel(app.Optimization);
            app.mLabel.Text = 'm';
            app.PaLabel = uilabel(app.Optimization);
            app.PaLabel.Text = 'Pa';
            app.NmLabel = uilabel(app.Optimization);
            app.NmLabel.Text = 'N.m';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = project_final

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end